classdef MARIEv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Button                    matlab.ui.control.Button
        ExitButton                matlab.ui.control.Button
        LicenseButton             matlab.ui.control.Button
        DocButton                 matlab.ui.control.Button
        Switch                    matlab.ui.control.Switch
        SimulationPanel           matlab.ui.container.Panel
        RunButton_4               matlab.ui.control.Button
        TuneCoilButton_4          matlab.ui.control.Button
        SetportsButton_4          matlab.ui.control.Button
        AxisRotationSwitchLabel   matlab.ui.control.Label
        AxisRotationSwitch        matlab.ui.control.Switch
        ZoomSwitchLabel           matlab.ui.control.Label
        ZoomSwitch                matlab.ui.control.Switch
        ClearButton               matlab.ui.control.Button
        InputsParametersPanel     matlab.ui.container.Panel
        Generate_3                matlab.ui.control.Button
        LoadButton_3              matlab.ui.control.Button
        InfoButton_3              matlab.ui.control.Button
        SaveButton_3              matlab.ui.control.Button
        RadioFrequencyCoilsPanel  matlab.ui.container.Panel
        LoadCoil_2                matlab.ui.control.Button
        PlotButton_2              matlab.ui.control.Button
        InfoButton_2              matlab.ui.control.Button
        SaveButton_2              matlab.ui.control.Button
        GenerateButton_2          matlab.ui.control.DropDown
        DisplaceButton_2          matlab.ui.control.Button
        BodyModelPanel            matlab.ui.container.Panel
        chooseBody                matlab.ui.control.DropDown
        InfoButton                matlab.ui.control.Button
        PlotButton                matlab.ui.control.Button
        LoadButton                matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        DisplaceButton            matlab.ui.control.Button
        SolutionPanel             matlab.ui.container.Panel
        VisualizeButton_5         matlab.ui.control.Button
        SaveButton_5              matlab.ui.control.Button
        LoadButton_5              matlab.ui.control.Button
        SaveButton_axes           matlab.ui.control.Button
        TextArea                  matlab.ui.control.TextArea
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % press to call new app window
    end

    properties (Access = public)
      
        RHBM
        COIL
        INC
        INP
        SOLUTION
        FLAGSIE
        obj_vis
        obj_rhbm_generator
        obj_coil_generator
        
    end
    
    methods (Access = private)
        
        
    end

    methods (Access = public)
    
        function update_body(app, rhbm_up)
            app.RHBM = rhbm_up;
        end
        function update_coil(app, coil_up)
            app.COIL = coil_up;
        end
        function update_incident(app, inc_up)
            app.INC = inc_up;
        end
        function update_inputs(app, inp_up)
            app.INP = inp_up;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % RHBM buttons
            app.InfoButton.Enable = 'off';
            app.PlotButton.Enable = 'off';
            app.SaveButton.Enable = 'off';
            app.DisplaceButton.Enable = 'off';
           
            % Coil buttons
            app.InfoButton_2.Enable = 'off';
            app.DisplaceButton_2.Enable = 'off';
            app.PlotButton_2.Enable = 'off';
            app.SaveButton_2.Enable = 'off';

            % Input buttons
            app.InfoButton_3.Enable = 'off';
            app.SaveButton_3.Enable = 'off';

            % Simulation buttons
            app.RunButton_4.Enable = 'off';
            app.SetportsButton_4.Enable = 'off';
            
            app.TuneCoilButton_4.Visible = 'off';
            if isfolder('Add-ons/tuning_matching_decoupling')
                app.TuneCoilButton_4.Visible = 'on';
            end

            % Solution buttons
            app.VisualizeButton_5.Enable = 'off';
            app.SaveButton_5.Enable = 'off';
            
            % External
            app.SaveButton_axes.Enable = 'off';
            
            
            app.obj_rhbm_generator = src_rhbm_generator.RHBM_generator;
            app.obj_coil_generator = src_coil_generator.COIL_generator;
            app.obj_vis = src_visualizer.Visualizer;
            
            if ~isfolder('data/bodies/Virtual_Family/')
                app.chooseBody.Items = {'No Model Selected','Homogeneous Cylinder','Homogeneous Cuboid','Homogeneous Sphere','Homogeneous Ellipsoid','Homogeneous Stadium','4 Compartment Cuboid','User_Body_Model'};
                app.chooseBody.Value = 'No Model Selected';
            else
                app.chooseBody.Items = {'No Model Selected','Realistic Human Model','Homogeneous Cylinder','Homogeneous Cuboid','Homogeneous Sphere','Homogeneous Ellipsoid','Homogeneous Stadium','4 Compartment Cuboid','User_Body_Model'};
                app.chooseBody.Value = 'No Model Selected';
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
        end

        % Callback function: ExitButton, UIFigure
        function UIFigureCloseRequest(app, event)
            close all
            delete(app)
            clearvars
        end

        % Button pushed function: LicenseButton
        function LicenseButtonPushed(app, event)
            value = app.Switch.Value;
            app.CallingApp = gui_license(value);
        end

        % Button pushed function: DocButton
        function DocButtonPushed(app, event)
            help_file_name = './gui/docs/documentation.pdf';
            if isunix
                system(['evince ' help_file_name ' &']);
            elseif ispc
                open(help_file_name);
            else
                msgbox(['Open ' help_file_name 'in the operating system'],'Product Help');
            end    
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if str2double(value)
                app.UIFigure.Color = [0.302 0.749 0.9294];
            else
                app.UIFigure.Color = [1 1 1];
            end
            if str2double(value)
                app.UIAxes.BackgroundColor = [0.302 0.749 0.9294];
            else
                app.UIAxes.BackgroundColor = [1 1 1];
            end
        end

        % Value changed function: chooseBody
        function chooseBodyValueChanged(app, event)
            value = app.chooseBody.Value;
            if strcmp(value,'No Model Selected')
                % self
                app.InfoButton.Enable = 'off';
                app.DisplaceButton.Enable = 'off';
                app.PlotButton.Enable = 'off';
                app.SaveButton.Enable = 'off';
                
                % simulation
                app.RunButton_4.Enable = 'off';
                
                % tune
                app.TuneCoilButton_4.Enable = 'off';
                
            elseif strcmp(value,'Realistic Human Model')
                app.InfoButton.Enable = 'on';
                app.DisplaceButton.Enable = 'on';
                app.PlotButton.Enable = 'on';
                app.SaveButton.Enable = 'on';
                app.CallingApp = app_RHBM_RHBM(app.Switch.Value,app);
                if strcmp(app.InfoButton_2.Enable,'on') && strcmp(app.InfoButton_3.Enable,'on')
                    % simulation
                    app.RunButton_4.Enable = 'on';
                    % tune
                    app.TuneCoilButton_4.Enable = 'on';
                end
                
            elseif strcmp(value,'Homogeneous Cuboid')
                app.InfoButton.Enable = 'on';
                app.DisplaceButton.Enable = 'on';
                app.PlotButton.Enable = 'on';
                app.SaveButton.Enable = 'on';
                app.CallingApp = app_RHBM_cuboid(app.Switch.Value,app);
                if strcmp(app.InfoButton_2.Enable,'on') && strcmp(app.InfoButton_3.Enable,'on')
                    % simulation
                    app.RunButton_4.Enable = 'on';
                    % tune
                    app.TuneCoilButton_4.Enable = 'on';
                end
            
            elseif strcmp(value,'Homogeneous Cylinder')
                app.InfoButton.Enable = 'on';
                app.DisplaceButton.Enable = 'on';
                app.PlotButton.Enable = 'on';
                app.SaveButton.Enable = 'on';
                app.CallingApp = app_RHBM_cylinder(app.Switch.Value,app);
                if strcmp(app.InfoButton_2.Enable,'on') && strcmp(app.InfoButton_3.Enable,'on')
                    % simulation
                    app.RunButton_4.Enable = 'on';
                    % tune
                    app.TuneCoilButton_4.Enable = 'on';
                end
                
            elseif strcmp(value,'Homogeneous Sphere')
                app.InfoButton.Enable = 'on';
                app.DisplaceButton.Enable = 'on';
                app.PlotButton.Enable = 'on';
                app.SaveButton.Enable = 'on';
                app.CallingApp = app_RHBM_sphere(app.Switch.Value,app);
                if strcmp(app.InfoButton_2.Enable,'on') && strcmp(app.InfoButton_3.Enable,'on')
                    % simulation
                    app.RunButton_4.Enable = 'on';
                    % tune
                    app.TuneCoilButton_4.Enable = 'on';
                end
                
            elseif strcmp(value,'Homogeneous Stadium')
                app.InfoButton.Enable = 'on';
                app.DisplaceButton.Enable = 'on';
                app.PlotButton.Enable = 'on';
                app.SaveButton.Enable = 'on';
                app.CallingApp = app_RHBM_stadium(app.Switch.Value,app);
                if strcmp(app.InfoButton_2.Enable,'on') && strcmp(app.InfoButton_3.Enable,'on')
                    % simulation
                    app.RunButton_4.Enable = 'on';
                    % tune
                    app.TuneCoilButton_4.Enable = 'on';
                end
                
            elseif strcmp(value,'Homogeneous Ellipsoid')
                app.InfoButton.Enable = 'on';
                app.DisplaceButton.Enable = 'on';
                app.PlotButton.Enable = 'on';
                app.SaveButton.Enable = 'on';
                app.CallingApp = app_RHBM_ellipsoid(app.Switch.Value,app);
                if strcmp(app.InfoButton_2.Enable,'on') && strcmp(app.InfoButton_3.Enable,'on')
                    % simulation
                    app.RunButton_4.Enable = 'on';
                    % tune
                    app.TuneCoilButton_4.Enable = 'on';
                end
                
            elseif strcmp(value,'4 Compartment Cuboid')
                app.InfoButton.Enable = 'on';
                app.DisplaceButton.Enable = 'on';
                app.PlotButton.Enable = 'on';
                app.SaveButton.Enable = 'on';
                app.CallingApp = app_RHBM_4comp_cuboid(app.Switch.Value,app);
                if strcmp(app.InfoButton_2.Enable,'on') && strcmp(app.InfoButton_3.Enable,'on')
                    % simulation
                    app.RunButton_4.Enable = 'on';
                    % tune
                    app.TuneCoilButton_4.Enable = 'on';
                end
                
            elseif strcmp(value,'User_Body_Model')
                app.InfoButton.Enable = 'on';
                app.DisplaceButton.Enable = 'on';
                app.PlotButton.Enable = 'on';
                app.SaveButton.Enable = 'on';
                rhbm_out = RHBM_User_Body_Model();
                app.RHBM = rhbm_out;
                if strcmp(app.InfoButton_2.Enable,'on') && strcmp(app.InfoButton_3.Enable,'on')
                    % simulation
                    app.RunButton_4.Enable = 'on';
                    % tune
                    app.TuneCoilButton_4.Enable = 'on';
                end
                
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            xd = app.RHBM.r(:,:,:,1);
            yd = app.RHBM.r(:,:,:,2);
            zd = app.RHBM.r(:,:,:,3);
            id = src_utils.scatterer_idx(app.RHBM);
            P = [xd(id) yd(id) zd(id)];
            k = boundary(P,1);
            trisurf(k,P(:,1),P(:,2),P(:,3),'Parent',app.UIAxes,'LineStyle','none');
            colormap(app.UIAxes,[linspace(0/256,30/256,256)', linspace(144/256,191/256,256)', ones(256,1)]);
            camlight(app.UIAxes,'headlight');
            lighting(app.UIAxes,'gouraud');
            hold(app.UIAxes,'on');
            xlabel(app.UIAxes,'x','interpreter','latex');
            ylabel(app.UIAxes,'y','interpreter','latex');
            zlabel(app.UIAxes,'z','interpreter','latex');
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.ZGrid = 'on';   
            app.SaveButton_axes.Enable = 'on';
        end

        % Value changed function: AxisRotationSwitch
        function AxisRotationSwitchValueChanged(app, event)
            value = app.AxisRotationSwitch.Value;
            
            if str2double(value)
                rotate3d(app.UIAxes,'on');
            else
                rotate3d(app.UIAxes,'off');
            end
        end

        % Value changed function: ZoomSwitch
        function ZoomSwitchValueChanged(app, event)
            value = app.ZoomSwitch.Value;
            
            if str2double(value)
                zoom(app.UIAxes,'on');
            else
                zoom(app.UIAxes,'off');
                app.UIAxes.XLimMode = 'auto';
                app.UIAxes.YLimMode = 'auto';
                app.UIAxes.ZLimMode = 'auto';
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            rhbm_file = uigetfile('./data/bodies/user','*.mat');
            if ~isnumeric(rhbm_file)
                S = load(rhbm_file);
                app.InfoButton.Enable = 'on';
                app.DisplaceButton.Enable = 'on';
                app.PlotButton.Enable = 'on';
                app.SaveButton.Enable = 'on';
                if strcmp(app.InfoButton_2.Enable,'on') && strcmp(app.InfoButton_3.Enable,'on')
                    app.TuneCoilButton_4.Enable = 'on';
                    app.RunButton_4.Enable = 'on';
                end
                app.RHBM = src_utils.properties_to_RHBM(S);
            end
        end

        % Button pushed function: LoadCoil_2
        function LoadCoil_2Pushed(app, event)
            coil_file = uigetfile({'./data/coils/msh_files/*.msh;*.mat'});
            if ~isnumeric(coil_file)
                app.InfoButton_2.Enable = 'on';
                app.SetportsButton_4.Enable = 'on';
                app.DisplaceButton_2.Enable = 'on';
                app.PlotButton_2.Enable = 'on';
                app.SaveButton_5.Enable = 'on';
                if strcmp(coil_file(end-2:end),'msh')
                    coil_file_pure = erase(coil_file,'./data/coils/msh_files/');
                    coil_file_pure = coil_file_pure(1:end-4);
                    sie_settings.CoilPortsFile = strcat('lumped_elements_files/',coil_file_pure,'.txt');
                    sie_settings.CoilMeshFile = strcat('msh_files/',coil_file_pure,'.msh');
                    sie_settings.Coil_Path = './data/coils';
                    coil_obj = src_geo.Coil_Loader(sie_settings);
                    coil_out = coil_obj.load_coil();
                    coil_out.name = coil_file_pure;
                    app.COIL = coil_out;   
                elseif strcmp(coil_file(end-2:end),'mat')
                    S = load(coil_file,'COIL');
                    app.COIL = S.COIL;
                end
                app.SaveButton_2.Enable = 'on';
                if strcmp(app.InfoButton.Enable,'on') && strcmp(app.InfoButton_3.Enable,'on')
                    app.TuneCoilButton_4.Enable = 'on';
                    app.RunButton_4.Enable = 'on';
                end
            end
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            pos = zeros(length(app.COIL.port),3);
            s = zeros(length(app.COIL.port),1);
            for i = 1:length(app.COIL.port)
                pos(i,:) = app.COIL.node(:,app.COIL.edge(1,find(app.COIL.index == app.COIL.port(i).t(1))));
                s(i) = i;
            end
            s = string(s);
            TR = triangulation(app.COIL.elem(1:3,:)',app.COIL.node');
            h.ax = app.UIAxes;
            h.surf = trisurf(TR,'Parent', h.ax);
            set(h.surf,'FaceColor',[0.72 0.45 0.2],'LineStyle','none','FaceAlpha',.8);
            hold(app.UIAxes,'on');
            xlabel(app.UIAxes,'x','interpreter','latex');
            ylabel(app.UIAxes,'y','interpreter','latex');
            zlabel(app.UIAxes,'z','interpreter','latex');
            text(app.UIAxes,pos(:,1),pos(:,2),pos(:,3),s,'HorizontalAlignment','left','Color','b','FontSize',10);
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.ZGrid = 'on';  
            app.SaveButton_axes.Enable = 'on';
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            try            
                app.UIAxes.cla;
            catch
                cla(app.UIAxes);
            end
             app.SaveButton_axes.Enable = 'off';
        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
            xmin = min(min(min(app.RHBM.r(:,:,:,1))));
            xmax = max(max(max(app.RHBM.r(:,:,:,1))));
            ymin = min(min(min(app.RHBM.r(:,:,:,2))));
            ymax = max(max(max(app.RHBM.r(:,:,:,2))));
            zmin = min(min(min(app.RHBM.r(:,:,:,3))));
            zmax = max(max(max(app.RHBM.r(:,:,:,3))));
            resolution = abs(app.RHBM.r(2,1,1,1)-app.RHBM.r(1,1,1,1));
            n1 = size(app.RHBM.r,1);
            n2 = size(app.RHBM.r,2);
            n3 = size(app.RHBM.r,3);
            voxels = n1*n2*n3;
            PWLun = voxels*12*8*16/1024^3;
            PWCun = voxels*3*8*16/1024^3;
            materials = length(unique(app.RHBM.epsilon_r(:)));
            materials_max_e = max(app.RHBM.epsilon_r(:));
            materials_min_e = min(app.RHBM.epsilon_r(:));
            materials_max_s = max(app.RHBM.sigma_e(:));
            materials_min_s = min(app.RHBM.sigma_e(:));
           
            fprintf(1,'\n\n');
            fprintf(1,'Information for: %s \n\n',app.RHBM.name);
            fprintf(1,'Domain range in X axis: %f to %f \n',xmin,xmax);
            fprintf(1,'Domain range in Y axis: %f to %f \n',ymin,ymax);
            fprintf(1,'Domain range in Z axis: %f to %f \n',zmin,zmax);
            fprintf(1,'Voxel resolution: %f \n',resolution);
            fprintf(1,'Domain dimension: %d x %d x %d \n',n1,n2,n3);
            fprintf(1,'Number of voxels: %d \n\n',voxels);
            fprintf(1,'Memory for the unknowns with PWC: %f GBs \n',PWCun);
            fprintf(1,'Memory for the unknowns with PWL: %f GBs \n\n',PWLun);
            fprintf(1,'Number of unique dielectric materials: %d \n',materials);
            fprintf(1,'Range of relative permittivity: %f to %f \n',materials_min_e,materials_max_e);
            fprintf(1,'Range of conductivity: %f to %f \n\n',materials_min_s,materials_max_s);
        end

        % Button pushed function: InfoButton_2
        function InfoButton_2Pushed2(app, event)
            el = 0;
            for i=1:length(app.COIL.port)
                el = el+ strcmp(app.COIL.port(i).type,'load');
            end
            Zmom = (size(app.COIL.edge,2))^2*16/1024^2;
            fprintf(1,'\n\n');
            fprintf(1,'Information for: %s \n\n',app.COIL.name);
            fprintf(1,'Number of RWG basis functions/edges: %d \n',size(app.COIL.edge,2));
            fprintf(1,'Estimated memory of Galerkin matrix: %f MBs \n',Zmom);
            fprintf(1,'Number of feeding ports: %d \n', size(app.COIL.port,1)-el);
            fprintf(1,'Number of lumped elements: %d \n', el);
        end

        % Button pushed function: VisualizeButton_5
        function VisualizeButton_5Pushed(app, event)
            value = app.Switch.Value;
            app.CallingApp = app_visualize_results(value,app);    
        end

        % Button pushed function: RunButton_4
        function RunButton_4Pushed(app, event)
            RHBM = app.RHBM;
            COIL = app.COIL;
            rhbm_name = RHBM.name;
            coil_name = COIL.name;
            save(strcat('./data/bodies/user/',rhbm_name,'.mat'),'RHBM','-v7.3');
            save(strcat('./data/coils/user/',coil_name,'.mat'),'COIL','-v7.3');
            ini = create_ini_vsie(app.RHBM,app.COIL,app.INP);
            
            simulator = src_task.EM_simulator();
            simulator.load_task(fullfile(ini));
            
            tic;
            
            simulator.run_task();
            
            warning('off', 'MATLAB:structOnObject');
            
            app.SOLUTION = struct(simulator.get_solution());
            app.SOLUTION.coil = simulator.task_runner_.coil;
            
            time_overall = toc;
            time_overall_str = datestr(seconds(time_overall),'HH:MM:SS');
            
            fprintf('Problem solved succesfully in %s.\n\n',time_overall_str);
            fprintf('You can visualize the results.\n\n');
            
            % Enable solution usability
            app.VisualizeButton_5.Enable = 'on';
            app.SaveButton_5.Enable = 'on';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            RHBM = app.RHBM;
            uisave({'RHBM'},'./data/bodies/user')
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            COIL = app.COIL;
            uisave({'COIL'},'./data/coils/user')
        end

        % Button pushed function: SaveButton_3
        function SaveButton_3Pushed(app, event)
            INP = app.INP;
            uisave({'INP'},'./data/inputs/user')   
        end

        % Button pushed function: LoadButton_3
        function LoadButton_3Pushed(app, event)
            inp_file = uigetfile('./data/inputs/user/','*.mat');
            if ~isnumeric(inp_file)
                S = load(inp_file,'INP');
                app.InfoButton_3.Enable = 'on';
                app.SaveButton_3.Enable = 'on';
                if ((strcmp(app.InfoButton_2.Enable,'on') || strcmp(app.InfoButton_2.Enable,'on')) && strcmp(app.InfoButton.Enable,'on'))
                    app.RunButton_4.Enable = 'on';
                end
                app.INP = S.INP;
            end
        end

        % Button pushed function: Generate_3
        function Generate_3ButtonPushed(app, event)
            value = app.Switch.Value;
            app.CallingApp = app_generate_advanced_input(value,app);               
            
            app.InfoButton_3.Enable = 'on';
            app.SaveButton_3.Enable = 'on';
            if strcmp(app.InfoButton.Enable,'on') && strcmp(app.InfoButton_2.Enable,'on')
                app.TuneCoilButton_4.Enable = 'on';
                app.RunButton_4.Enable = 'on';
            end
        end

        % Button pushed function: SaveButton_5
        function SaveButton_5Pushed2(app, event)
            SOLUTION = app.SOLUTION;
            uisave({'SOLUTION'},'./data/solutions');       
        end

        % Value changed function: GenerateButton_2
        function GenerateButton_2ValueChanged(app, event)
            value = app.GenerateButton_2.Value;
            if strcmp(value,'No Model Selected')
                app.InfoButton_2.Enable = 'off';
                app.SetportsButton_4.Enable = 'off';
                app.DisplaceButton_2.Enable = 'off';
                app.SaveButton_2.Enable = 'off';
                app.PlotButton_2.Enable = 'off';
                app.TuneCoilButton_4.Enable = 'off';
                app.RunButton_4.Enable = 'off';
            else 
                app.InfoButton_2.Enable = 'on';
                app.SetportsButton_4.Enable = 'on';
                app.DisplaceButton_2.Enable = 'on';
                app.SaveButton_2.Enable = 'on';
                app.PlotButton_2.Enable = 'on';
                if strcmp(value,'Triangular Coil')
                    app.CallingApp = app_COIL_Triangular_Coil(app.Switch.Value,app);
                elseif strcmp(value,'Octa Coil')
                    app.CallingApp = app_COIL_Octa_Coil(app.Switch.Value,app);
                elseif strcmp(value,'Overlap Coil')
                    app.CallingApp = app_COIL_Overlap_Coil(app.Switch.Value,app);
                elseif strcmp(value,'Wiggins Coil')
                    app.CallingApp = app_COIL_Wiggins_Coil(app.Switch.Value,app);
                elseif strcmp(value,'Self Decoupled Coil')
                    app.CallingApp = app_COIL_Self_Coil(app.Switch.Value,app);
                elseif strcmp(value,'Single Row Coil')
                    app.CallingApp = app_COIL_SingleRow_Coil(app.Switch.Value,app);
                elseif strcmp(value,'Birdcage Coil')
                    app.CallingApp = app_COIL_Birdcage_Coil(app.Switch.Value,app);
                elseif strcmp(value,'Elliptical Triangular Coil')
                    app.CallingApp = app_COIL_EllipticalTriangular_Coil(app.Switch.Value,app);
                end
                if strcmp(app.InfoButton.Enable,'on') && strcmp(app.InfoButton_3.Enable,'on')
                    app.TuneCoilButton_4.Enable = 'on';
                    app.RunButton_4.Enable = 'on';
                end
                
            end
        end

        % Button pushed function: InfoButton_3
        function InfoButton_3Pushed(app, event)
            fprintf(1,'\n\n');
            fprintf(1,'Information for Inputs \n\n');
            if strcmp(app.InfoButton.Visible,'on')
                fprintf(1,'The solver will run in: %s \n',app.INP.PU);
                fprintf(1,'The polarisation current will use %s basis functions \n',app.INP.PWX);
            end
            if app.INP.freq.range > 1
                if strcmp(app.INP.freq.scaling,'Linear')
                    spacing = 'linearly';
                elseif strcmp(app.INP.freq.scaling,'Logarithmic')
                    spacing = 'logarithmically';
                end
                fprintf(1,'The simulation will run for %d frequencies %s spaced in range between %f Hz and %f Hz \n',app.INP.freq.range,spacing,app.INP.freq.freq_min,app.INP.freq.freq_max);
            else
                fprintf(1,'The operating frequency is: %f Hz \n',app.INP.freq.freq_min);
            end
        end

        % Button pushed function: LoadButton_5
        function LoadButton_5Pushed(app, event)
            solution_file = uigetfile('./data/solutions/','*.mat');
            if ~isnumeric(solution_file)
                S = load(solution_file);
                app.SOLUTION = S.SOL;
                app.VisualizeButton_5.Enable = 'on';
                app.SaveButton_5.Enable = 'on';
            end 
        end

        % Button pushed function: SaveButton_axes
        function SaveGeometryPushed(app, event)
            
            if app.InfoButton_2.Enable == 'on'
                pivot = length(app.COIL.port);
            else
                pivot = 0;
            end
            
            if size(app.UIAxes.Children,1)==3+pivot           
                xd = app.RHBM.r(:,:,:,1);
                yd = app.RHBM.r(:,:,:,2);
                zd = app.RHBM.r(:,:,:,3);
                id = app.RHBM.idxS;
                app.obj_vis.visualize_coil_and_body(xd,yd,zd,id,app.COIL);   
            elseif size(app.UIAxes.Children,1) == 2           
                xd = app.RHBM.r(:,:,:,1);
                yd = app.RHBM.r(:,:,:,2);
                zd = app.RHBM.r(:,:,:,3);
                id = app.RHBM.idxS;
                app.obj_vis.visualize_body(xd,yd,zd,id);
            elseif size(app.UIAxes.Children,1)==1+pivot   
                app.obj_vis.visualize_coil(app.COIL);
            end
        end

        % Button pushed function: DisplaceButton
        function DisplaceButtonPushed(app, event)
            app.CallingApp = app_RHBM_displacement(app.Switch.Value,app);
            
            try 
                app.UIAxes.cla;
            catch 
                cla(app.UIAxes);
            end
            app.SaveButton_axes.Enable = 'off';    
        end

        % Button pushed function: DisplaceButton_2
        function DisplaceButton_2Pushed(app, event)
            app.CallingApp = app_COIL_Displacement(app.Switch.Value,app);
            
            try
                app.UIAxes.cla;
            catch
                cla(app.UIAxes);
            end
            app.SaveButton_axes.Enable = 'off';            
        end

        % Button pushed function: SetportsButton_4
        function SetportsButton_4Pushed(app, event)
            if isfolder('Add-ons/tuning_matching_decoupling')
                app.CallingApp = app_COIL_Set_Ports(app.Switch.Value,app);
            else
                app.CallingApp = app_COIL_Set_Ports_Simple(app.Switch.Value,app);    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'Marie2logo.jpg';
            app.Button.Text = '';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton.Text = 'Exit';
            app.LicenseButton = uibutton(app.UIFigure, 'push');
            app.LicenseButton.ButtonPushedFcn = createCallbackFcn(app, @LicenseButtonPushed, true);
            app.LicenseButton.Text = 'License';
            app.DocButton = uibutton(app.UIFigure, 'push');
            app.DocButton.ButtonPushedFcn = createCallbackFcn(app, @DocButtonPushed, true);
            app.DocButton.Text = 'Bibtex';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'1', '0'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '1';
            app.SimulationPanel = uipanel(app.UIFigure);
            app.SimulationPanel.Title = 'Simulation';
            app.RunButton_4 = uibutton(app.SimulationPanel, 'push');
            app.RunButton_4.ButtonPushedFcn = createCallbackFcn(app, @RunButton_4Pushed, true);
            app.RunButton_4.Icon = 'run_ico.png';
            app.RunButton_4.Text = 'Run';
            app.TuneCoilButton_4 = uibutton(app.SimulationPanel, 'push');
            app.TuneCoilButton_4.Icon = 'tune_ico.png';
            app.TuneCoilButton_4.Text = 'Tune coil';
            app.SetportsButton_4 = uibutton(app.SimulationPanel, 'push');
            app.SetportsButton_4.ButtonPushedFcn = createCallbackFcn(app, @SetportsButton_4Pushed, true);
            app.SetportsButton_4.Icon = 'le_ico.png';
            app.SetportsButton_4.Text = 'Set ports';
            app.AxisRotationSwitchLabel = uilabel(app.UIFigure);
            app.AxisRotationSwitchLabel.Text = 'Axis Rotation';
            app.AxisRotationSwitch = uiswitch(app.UIFigure, 'slider');
            app.AxisRotationSwitch.Items = {'0', '1'};
            app.AxisRotationSwitch.ValueChangedFcn = createCallbackFcn(app, @AxisRotationSwitchValueChanged, true);
            app.AxisRotationSwitch.Value = '0';
            app.ZoomSwitchLabel = uilabel(app.UIFigure);
            app.ZoomSwitchLabel.Text = 'Zoom';
            app.ZoomSwitch = uiswitch(app.UIFigure, 'slider');
            app.ZoomSwitch.Items = {'0', '1'};
            app.ZoomSwitch.ValueChangedFcn = createCallbackFcn(app, @ZoomSwitchValueChanged, true);
            app.ZoomSwitch.Value = '0';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.InputsParametersPanel = uipanel(app.UIFigure);
            app.InputsParametersPanel.Title = 'Inputs Parameters';
            app.Generate_3 = uibutton(app.InputsParametersPanel, 'push');
            app.Generate_3.ButtonPushedFcn = createCallbackFcn(app, @Generate_3ButtonPushed, true);
            app.Generate_3.Text = 'Set';
            app.LoadButton_3 = uibutton(app.InputsParametersPanel, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_3Pushed, true);
            app.LoadButton_3.Icon = 'load_ico.png';
            app.LoadButton_3.Text = 'Load';
            app.InfoButton_3 = uibutton(app.InputsParametersPanel, 'push');
            app.InfoButton_3.ButtonPushedFcn = createCallbackFcn(app, @InfoButton_3Pushed, true);
            app.InfoButton_3.Icon = 'info_ico.png';
            app.InfoButton_3.Text = 'Info';
            app.SaveButton_3 = uibutton(app.InputsParametersPanel, 'push');
            app.SaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_3Pushed, true);
            app.SaveButton_3.Icon = 'save_ico.png';
            app.SaveButton_3.Text = 'Save';
            app.RadioFrequencyCoilsPanel = uipanel(app.UIFigure);
            app.RadioFrequencyCoilsPanel.Title = 'Radio-Frequency Coils';
            app.LoadCoil_2 = uibutton(app.RadioFrequencyCoilsPanel, 'push');
            app.LoadCoil_2.ButtonPushedFcn = createCallbackFcn(app, @LoadCoil_2Pushed, true);
            app.LoadCoil_2.Icon = 'load_ico.png';
            app.LoadCoil_2.Text = 'Load';
            app.PlotButton_2 = uibutton(app.RadioFrequencyCoilsPanel, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.InfoButton_2 = uibutton(app.RadioFrequencyCoilsPanel, 'push');
            app.InfoButton_2.ButtonPushedFcn = createCallbackFcn(app, @InfoButton_2Pushed2, true);
            app.InfoButton_2.Icon = 'info_ico.png';
            app.InfoButton_2.Text = 'Info';
            app.SaveButton_2 = uibutton(app.RadioFrequencyCoilsPanel, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Icon = 'save_ico.png';
            app.SaveButton_2.Text = 'Save';
            app.GenerateButton_2 = uidropdown(app.RadioFrequencyCoilsPanel);
            app.GenerateButton_2.Items = {'No Model Selected', 'Triangular Coil', 'Octa Coil', 'Overlap Coil', 'Self Decoupled Coil', 'Wiggins Coil', 'Elliptical Triangular Coil', 'Single Row Coil'};
            app.GenerateButton_2.ValueChangedFcn = createCallbackFcn(app, @GenerateButton_2ValueChanged, true);
            app.GenerateButton_2.Value = 'No Model Selected';
            app.DisplaceButton_2 = uibutton(app.RadioFrequencyCoilsPanel, 'push');
            app.DisplaceButton_2.ButtonPushedFcn = createCallbackFcn(app, @DisplaceButton_2Pushed, true);
            app.DisplaceButton_2.Icon = 'displace_ico.png';
            app.DisplaceButton_2.Text = 'Displace';
            app.BodyModelPanel = uipanel(app.UIFigure);
            app.BodyModelPanel.Title = 'Body Model';
            app.chooseBody = uidropdown(app.BodyModelPanel);
            app.chooseBody.Items = {};
            app.chooseBody.ValueChangedFcn = createCallbackFcn(app, @chooseBodyValueChanged, true);
            app.chooseBody.Value = {};
            app.InfoButton = uibutton(app.BodyModelPanel, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Icon = 'info_ico.png';
            app.InfoButton.Text = 'Info';
            app.PlotButton = uibutton(app.BodyModelPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.LoadButton = uibutton(app.BodyModelPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Icon = 'load_ico.png';
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.BodyModelPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'save_ico.png';
            app.SaveButton.Text = 'Save';
            app.DisplaceButton = uibutton(app.BodyModelPanel, 'push');
            app.DisplaceButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaceButtonPushed, true);
            app.DisplaceButton.Icon = 'displace_ico.png';
            app.DisplaceButton.Text = 'Displace';
            app.SolutionPanel = uipanel(app.UIFigure);
            app.SolutionPanel.Title = 'Solution';
            app.VisualizeButton_5 = uibutton(app.SolutionPanel, 'push');
            app.VisualizeButton_5.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButton_5Pushed, true);
            app.VisualizeButton_5.Text = 'Visualize';
            app.SaveButton_5 = uibutton(app.SolutionPanel, 'push');
            app.SaveButton_5.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_5Pushed2, true);
            app.SaveButton_5.Icon = 'save_ico.png';
            app.SaveButton_5.Text = 'Save';
            app.LoadButton_5 = uibutton(app.SolutionPanel, 'push');
            app.LoadButton_5.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_5Pushed, true);
            app.LoadButton_5.Icon = 'load_ico.png';
            app.LoadButton_5.Text = 'Load';
            app.SaveButton_axes = uibutton(app.UIFigure, 'push');
            app.SaveButton_axes.ButtonPushedFcn = createCallbackFcn(app, @SaveGeometryPushed, true);
            app.SaveButton_axes.Icon = 'save_ico.png';
            app.SaveButton_axes.Text = 'Save';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'MAgnetic Resonance Integral Equation suite'; ''; 'MARIE computes the interaction between electromagnetic waves, radiofrequency coils and biological tissue. '};
            app.UIAxes = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MARIEv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end