classdef app_COIL_Displacement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COIL_Displacement_app  matlab.ui.Figure
        ExitButton             matlab.ui.control.Button
        yEditField             matlab.ui.control.NumericEditField
        yEditFieldLabel        matlab.ui.control.Label
        zEditField             matlab.ui.control.NumericEditField
        zEditFieldLabel        matlab.ui.control.Label
        xEditField             matlab.ui.control.NumericEditField
        xEditFieldLabel        matlab.ui.control.Label
        EditField              matlab.ui.control.EditField
        DisplaceCOILButton     matlab.ui.control.Button
        Button                 matlab.ui.control.Button
        resEditField           matlab.ui.control.NumericEditField
        EditField_5            matlab.ui.control.EditField
    end


    properties (Access = private)
        CallingApp   % Main app         
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, value, marieapp)
            if str2double(value)
                app.COIL_Displacement_app.Color = [0.302 0.749 0.9294];
            else
                app.COIL_Displacement_app.Color = [1 1 1];
            end
            app.CallingApp = marieapp;
        end

        % Callback function: COIL_Displacement_app, ExitButton
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: DisplaceCOILButton
        function DisplaceCOILButtonPushed(app, event)
            Cnt = [app.xEditField.Value app.yEditField.Value app.zEditField.Value];
            rot = app.resEditField.Value;
            coil_out = app.CallingApp.obj_coil_generator.COIL_Displacement(Cnt,rot,app.CallingApp.COIL);
            update_coil(app.CallingApp, coil_out);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COIL_Displacement_app = uifigure('Visible', 'off');
            app.COIL_Displacement_app.Name = 'UI Figure';
            app.COIL_Displacement_app.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton = uibutton(app.COIL_Displacement_app, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton.Text = 'Exit';
            app.yEditField = uieditfield(app.COIL_Displacement_app, 'numeric');
            app.yEditFieldLabel = uilabel(app.COIL_Displacement_app);
            app.yEditFieldLabel.Text = 'y';
            app.zEditField = uieditfield(app.COIL_Displacement_app, 'numeric');
            app.zEditFieldLabel = uilabel(app.COIL_Displacement_app);
            app.zEditFieldLabel.Text = 'z';
            app.xEditField = uieditfield(app.COIL_Displacement_app, 'numeric');
            app.xEditFieldLabel = uilabel(app.COIL_Displacement_app);
            app.xEditFieldLabel.Text = 'x';
            app.EditField = uieditfield(app.COIL_Displacement_app, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Displace';
            app.DisplaceCOILButton = uibutton(app.COIL_Displacement_app, 'push');
            app.DisplaceCOILButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaceCOILButtonPushed, true);
            app.DisplaceCOILButton.Text = 'Displace COIL';
            app.Button = uibutton(app.COIL_Displacement_app, 'push');
            app.Button.Icon = 'Marie2logo.jpg';
            app.Button.Text = '';
            app.resEditField = uieditfield(app.COIL_Displacement_app, 'numeric');
            app.resEditField.Limits = [0 360];
            app.EditField_5 = uieditfield(app.COIL_Displacement_app, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_5.Value = 'Rotate (degrees)';
            % Show the figure after all components are created
            app.COIL_Displacement_app.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_COIL_Displacement(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COIL_Displacement_app)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COIL_Displacement_app)
        end
    end
end