classdef app_COIL_EllipticalTriangular_Coil < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COIL_EllipticalTriangular_Coil_app  matlab.ui.Figure
        ExitButton        matlab.ui.control.Button
        EditField_3       matlab.ui.control.EditField
        RadEditField      matlab.ui.control.NumericEditField
        EditField_4       matlab.ui.control.EditField
        LenEditField      matlab.ui.control.NumericEditField
        CreateCoilButton  matlab.ui.control.Button
        Button            matlab.ui.control.Button
        meshEditField     matlab.ui.control.NumericEditField
        EditField_5       matlab.ui.control.EditField
        EditField_6       matlab.ui.control.EditField
        RadEditField_2    matlab.ui.control.NumericEditField
        EditField_7       matlab.ui.control.EditField
        LenEditField_2    matlab.ui.control.NumericEditField
        ShieldCheckBox    matlab.ui.control.CheckBox
        EditField_sr      matlab.ui.control.EditField
        value_sr          matlab.ui.control.NumericEditField
        EditField_sl      matlab.ui.control.EditField
        value_sl          matlab.ui.control.NumericEditField
        value_sm          matlab.ui.control.NumericEditField
        EditField_sm      matlab.ui.control.EditField
    end


    properties (Access = private)
        CallingApp   % Main app         
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, value, marieapp)
            if str2double(value)
                app.COIL_EllipticalTriangular_Coil_app.Color = [0.302 0.749 0.9294];
            else
                app.COIL_EllipticalTriangular_Coil_app.Color = [1 1 1];
            end
            app.CallingApp = marieapp;
        end

        % Callback function: COIL_EllipticalTriangular_Coil_app, 
        % ExitButton
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: CreateCoilButton
        function CreateCoilButtonPushed(app, event)
            major = app.RadEditField.Value;
            minor = app.RadEditField_2.Value;
            len = app.LenEditField.Value;
            width = app.LenEditField_2.Value;
            meshing = app.meshEditField.Value;
            
            value = app.ShieldCheckBox.Value;
            if value
                shield.flag = 1;
                shield.radius = app.value_sr.Value;
                shield.length = app.value_sl.Value;
                shield.meshing = app.value_sm.Value;
            else
                shield.flag = 0;
                shield.radius = [];
                shield.length = [];
                shield.meshing = [];
            end
            
            coil_file = app.CallingApp.obj_coil_generator.COIL_EllipticalTriangularCoil(major,minor,len,width,meshing,shield);
            coil_file_pure = erase(coil_file,'./data/coils/msh_files/');
            coil_file_pure = coil_file_pure(1:end-4);
            sie_settings.CoilPortsFile = strcat('lumped_elements_files/',extractBefore(coil_file_pure,'_'),'.txt');
            sie_settings.CoilMeshFile = strcat('msh_files/',coil_file_pure,'.msh');
            sie_settings.Coil_Path = './data/coils';
            coil_obj = src_geo.Coil_Loader(sie_settings);
            coil_out = coil_obj.load_coil();
            coil_out.name = coil_file_pure;
            update_coil(app.CallingApp, coil_out);
            delete(app)
        end

        % Value changed function: ShieldCheckBox
        function ShieldCheckBoxValueChanged(app, event)
            value = app.ShieldCheckBox.Value;
            
            if value
                app.EditField_sr.Enable = 'on';
                app.EditField_sl.Enable = 'on';
                app.EditField_sm.Enable = 'on';
                app.value_sm.Enable = 'on';
                app.value_sr.Enable = 'on';
                app.value_sl.Enable = 'on';
            else
                app.EditField_sr.Enable = 'off';
                app.EditField_sl.Enable = 'off';
                app.EditField_sm.Enable = 'off';
                app.value_sm.Enable = 'off';
                app.value_sr.Enable = 'off';
                app.value_sl.Enable = 'off';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COIL_EllipticalTriangular_Coil_app = uifigure('Visible', 'off');
            app.COIL_EllipticalTriangular_Coil_app.Name = 'UI Figure';
            app.COIL_EllipticalTriangular_Coil_app.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton = uibutton(app.COIL_EllipticalTriangular_Coil_app, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton.Text = 'Exit';
            app.EditField_3 = uieditfield(app.COIL_EllipticalTriangular_Coil_app, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Value = 'x Semi-Axis';
            app.RadEditField = uieditfield(app.COIL_EllipticalTriangular_Coil_app, 'numeric');
            app.RadEditField.Limits = [0.01 1];
            app.RadEditField.Value = 0.13;
            app.EditField_4 = uieditfield(app.COIL_EllipticalTriangular_Coil_app, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Value = 'Length';
            app.LenEditField = uieditfield(app.COIL_EllipticalTriangular_Coil_app, 'numeric');
            app.LenEditField.Limits = [0.01 1];
            app.LenEditField.Value = 0.3;
            app.CreateCoilButton = uibutton(app.COIL_EllipticalTriangular_Coil_app, 'push');
            app.CreateCoilButton.ButtonPushedFcn = createCallbackFcn(app, @CreateCoilButtonPushed, true);
            app.CreateCoilButton.Text = 'Create Coil';
            app.Button = uibutton(app.COIL_EllipticalTriangular_Coil_app, 'push');
            app.Button.Icon = 'Marie2logo.jpg';
            app.Button.Text = '';
            app.meshEditField = uieditfield(app.COIL_EllipticalTriangular_Coil_app, 'numeric');
            app.meshEditField.Limits = [0.0001 0.1];
            app.meshEditField.Value = 0.01;
            app.EditField_5 = uieditfield(app.COIL_EllipticalTriangular_Coil_app, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_5.Value = 'Meshing';
            app.EditField_6 = uieditfield(app.COIL_EllipticalTriangular_Coil_app, 'text');
            app.EditField_6.Editable = 'off';
            app.EditField_6.Value = 'y Semi-Axis';
            app.RadEditField_2 = uieditfield(app.COIL_EllipticalTriangular_Coil_app, 'numeric');
            app.RadEditField_2.Limits = [0.01 1];
            app.RadEditField_2.Value = 0.12;
            app.EditField_7 = uieditfield(app.COIL_EllipticalTriangular_Coil_app, 'text');
            app.EditField_7.Editable = 'off';
            app.EditField_7.Value = 'Width';
            app.LenEditField_2 = uieditfield(app.COIL_EllipticalTriangular_Coil_app, 'numeric');
            app.LenEditField_2.Limits = [0.001 1];
            app.LenEditField_2.Value = 0.01;
            app.ShieldCheckBox = uicheckbox(app.COIL_EllipticalTriangular_Coil_app);
            app.ShieldCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShieldCheckBoxValueChanged, true);
            app.ShieldCheckBox.Text = 'Shield';
            app.EditField_sr = uieditfield(app.COIL_EllipticalTriangular_Coil_app, 'text');
            app.EditField_sr.Editable = 'off';
            app.EditField_sr.Enable = 'off';
            app.EditField_sr.Value = 'Shield Radius';
            app.value_sr = uieditfield(app.COIL_EllipticalTriangular_Coil_app, 'numeric');
            app.value_sr.Limits = [0.01 1];
            app.value_sr.Enable = 'off';
            app.value_sr.Value = 0.122;
            app.EditField_sl = uieditfield(app.COIL_EllipticalTriangular_Coil_app, 'text');
            app.EditField_sl.Editable = 'off';
            app.EditField_sl.Enable = 'off';
            app.EditField_sl.Value = 'Shield Length';
            app.value_sl = uieditfield(app.COIL_EllipticalTriangular_Coil_app, 'numeric');
            app.value_sl.Limits = [0.01 1];
            app.value_sl.Enable = 'off';
            app.value_sl.Value = 0.16;
            app.value_sm = uieditfield(app.COIL_EllipticalTriangular_Coil_app, 'numeric');
            app.value_sm.Limits = [0.0001 0.1];
            app.value_sm.Enable = 'off';
            app.value_sm.Value = 0.1;
            app.EditField_sm = uieditfield(app.COIL_EllipticalTriangular_Coil_app, 'text');
            app.EditField_sm.Editable = 'off';
            app.EditField_sm.Enable = 'off';
            app.EditField_sm.Value = 'Meshing';
            % Show the figure after all components are created
            app.COIL_EllipticalTriangular_Coil_app.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_COIL_EllipticalTriangular_Coil(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COIL_EllipticalTriangular_Coil_app)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COIL_EllipticalTriangular_Coil_app)
        end
    end
end