classdef app_COIL_Set_Ports < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COIL_Set_Ports_app  matlab.ui.Figure
        ExitButton          matlab.ui.control.Button
        UpdatePortsButton   matlab.ui.control.Button
    end


    properties (Access = private)
        ButtonsAll
        CallingApp   % Main app     
    end

    methods (Access = private)
            % Create ValueChangedFcn callback
            function ValueChangedPort(app,src,event,i,L)
                
                if L>1
                    if strcmp(app.ButtonsAll.a1(i).Value,'Lumped Element')
                        app.ButtonsAll.a2(i).Items = {'Capacitor','Variable Capacitor','Inductor','Mutual Inductor','Resistor'};
                    else
                        app.ButtonsAll.a2(i).Items = {'Empty','Capacitor','Variable Capacitor','Inductor','Resistor'};
                    end
                else
                    if strcmp(app.ButtonsAll.a1(i).Value,'Lumped Element')
                        app.ButtonsAll.a2(i).Items = {'Capacitor','Variable Capacitor','Inductor','Resistor'};
                    else
                        app.ButtonsAll.a2(i).Items = {'Empty','Capacitor','Variable Capacitor','Inductor','Resistor'};
                    end
                end
                    
                
                if strcmp(app.ButtonsAll.a2(i).Value,'Empty')
                    app.ButtonsAll.a3(i).Visible = 'off';
                    app.ButtonsAll.a4(i).Visible = 'off';
                    app.ButtonsAll.a5(i).Visible = 'off';
                    app.ButtonsAll.a6(i).Visible = 'off';
                    app.ButtonsAll.a7(i).Visible = 'off';
                    app.ButtonsAll.a8(i).Visible = 'off';
                    app.ButtonsAll.a9(i).Visible = 'off';
                    app.ButtonsAll.a10(i).Visible = 'off';
                    app.ButtonsAll.a12(i).Visible = 'off';
                    app.ButtonsAll.a13(i).Visible = 'off';
                elseif strcmp(app.ButtonsAll.a2(i).Value,'Variable Capacitor')
                    app.ButtonsAll.a7(i).Text = 'Initial Value';
                    app.ButtonsAll.a3(i).Visible = 'on';
                    app.ButtonsAll.a4(i).Visible = 'on';
                    app.ButtonsAll.a5(i).Visible = 'on';
                    app.ButtonsAll.a6(i).Visible = 'on';
                    app.ButtonsAll.a7(i).Visible = 'on';
                    app.ButtonsAll.a8(i).Visible = 'on';
                    app.ButtonsAll.a9(i).Visible = 'on';
                    app.ButtonsAll.a10(i).Visible = 'off';
                    app.ButtonsAll.a12(i).Visible = 'off';
                    app.ButtonsAll.a13(i).Visible = 'off';
                elseif strcmp(app.ButtonsAll.a2(i).Value,'Resistor')
                    app.ButtonsAll.a7(i).Text = 'Value';
                    app.ButtonsAll.a3(i).Visible = 'on';
                    app.ButtonsAll.a4(i).Visible = 'on';
                    app.ButtonsAll.a5(i).Visible = 'off';
                    app.ButtonsAll.a6(i).Visible = 'off';
                    app.ButtonsAll.a7(i).Visible = 'on';
                    app.ButtonsAll.a8(i).Visible = 'off';
                    app.ButtonsAll.a9(i).Visible = 'off';
                    app.ButtonsAll.a10(i).Visible = 'off';
                    app.ButtonsAll.a12(i).Visible = 'off';
                    app.ButtonsAll.a13(i).Visible = 'off';
                elseif strcmp(app.ButtonsAll.a2(i).Value,'Mutual Inductor')
                    app.ButtonsAll.a7(i).Text = 'Value';
                    app.ButtonsAll.a3(i).Visible = 'on';
                    app.ButtonsAll.a4(i).Visible = 'on';
                    app.ButtonsAll.a5(i).Visible = 'off';
                    app.ButtonsAll.a6(i).Visible = 'off';
                    app.ButtonsAll.a7(i).Visible = 'on';
                    app.ButtonsAll.a8(i).Visible = 'off';
                    app.ButtonsAll.a9(i).Visible = 'off';
                    app.ButtonsAll.a10(i).Visible = 'on';
                    app.ButtonsAll.a12(i).Visible = 'on';
                    app.ButtonsAll.a13(i).Visible = 'on';
                else
                    app.ButtonsAll.a7(i).Text = 'Value';
                    app.ButtonsAll.a3(i).Visible = 'on';
                    app.ButtonsAll.a4(i).Visible = 'off';
                    app.ButtonsAll.a5(i).Visible = 'off';
                    app.ButtonsAll.a6(i).Visible = 'off';
                    app.ButtonsAll.a7(i).Visible = 'on';
                    app.ButtonsAll.a8(i).Visible = 'off';
                    app.ButtonsAll.a9(i).Visible = 'off';
                    app.ButtonsAll.a10(i).Visible = 'off';
                    app.ButtonsAll.a12(i).Visible = 'off';
                    app.ButtonsAll.a13(i).Visible = 'off';
                end
                    
            end
            
            % Create ValueChangedFcn callback
            function ValueChangedElement(app,src,event,i,L)
                
                if strcmp(app.ButtonsAll.a2(i).Value,'Empty')
                    app.ButtonsAll.a3(i).Visible = 'off';
                    app.ButtonsAll.a4(i).Visible = 'off';
                    app.ButtonsAll.a5(i).Visible = 'off';
                    app.ButtonsAll.a6(i).Visible = 'off';
                    app.ButtonsAll.a7(i).Visible = 'off';
                    app.ButtonsAll.a8(i).Visible = 'off';
                    app.ButtonsAll.a9(i).Visible = 'off';
                    app.ButtonsAll.a10(i).Visible = 'off';
                    app.ButtonsAll.a12(i).Visible = 'off';
                    app.ButtonsAll.a13(i).Visible = 'off';
                elseif strcmp(app.ButtonsAll.a2(i).Value,'Variable Capacitor')
                    app.ButtonsAll.a7(i).Text = 'Initial Value';
                    app.ButtonsAll.a3(i).Visible = 'on';
                    app.ButtonsAll.a4(i).Visible = 'off';
                    app.ButtonsAll.a5(i).Visible = 'on';
                    app.ButtonsAll.a6(i).Visible = 'on';
                    app.ButtonsAll.a7(i).Visible = 'on';
                    app.ButtonsAll.a8(i).Visible = 'on';
                    app.ButtonsAll.a9(i).Visible = 'on';
                    app.ButtonsAll.a10(i).Visible = 'off';
                    app.ButtonsAll.a12(i).Visible = 'off';
                    app.ButtonsAll.a13(i).Visible = 'off';
                elseif strcmp(app.ButtonsAll.a2(i).Value,'Resistor')
                    app.ButtonsAll.a7(i).Text = 'Value (Re{},Im{})';
                    app.ButtonsAll.a3(i).Visible = 'on';
                    app.ButtonsAll.a4(i).Visible = 'on';
                    app.ButtonsAll.a5(i).Visible = 'off';
                    app.ButtonsAll.a6(i).Visible = 'off';
                    app.ButtonsAll.a7(i).Visible = 'on';
                    app.ButtonsAll.a8(i).Visible = 'off';
                    app.ButtonsAll.a9(i).Visible = 'off';
                    app.ButtonsAll.a10(i).Visible = 'off';
                    app.ButtonsAll.a12(i).Visible = 'off';
                    app.ButtonsAll.a13(i).Visible = 'off';
                elseif strcmp(app.ButtonsAll.a2(i).Value,'Mutual Inductor')
                    
                    app.ButtonsAll.a7(i).Text = 'Value';
                    app.ButtonsAll.a3(i).Visible = 'on';
                    app.ButtonsAll.a4(i).Visible = 'off';
                    app.ButtonsAll.a5(i).Visible = 'off';
                    app.ButtonsAll.a6(i).Visible = 'off';
                    app.ButtonsAll.a7(i).Visible = 'on';
                    app.ButtonsAll.a8(i).Visible = 'off';
                    app.ButtonsAll.a9(i).Visible = 'off';
                    app.ButtonsAll.a10(i).Visible = 'on';
                    app.ButtonsAll.a12(i).Visible = 'on';
                    app.ButtonsAll.a13(i).Visible = 'on';
                    
                    if i == 1
                        app.ButtonsAll.a7(2).Text = 'Value';
                        app.ButtonsAll.a3(2).Visible = 'on';
                        app.ButtonsAll.a4(2).Visible = 'off';
                        app.ButtonsAll.a5(2).Visible = 'off';
                        app.ButtonsAll.a6(2).Visible = 'off';
                        app.ButtonsAll.a7(2).Visible = 'on';
                        app.ButtonsAll.a8(2).Visible = 'off';
                        app.ButtonsAll.a9(2).Visible = 'off';
                        app.ButtonsAll.a10(2).Visible = 'on';
                        app.ButtonsAll.a12(2).Visible = 'on';
                        app.ButtonsAll.a13(2).Visible = 'on';
                        app.ButtonsAll.a1(2).Value = 'Lumped Element';
                        app.ButtonsAll.a2(2).Items = {'Capacitor','Variable Capacitor','Inductor','Mutual Inductor','Resistor'};
                        app.ButtonsAll.a2(2).Value = 'Mutual Inductor';
                        app.ButtonsAll.a12(2).Value = app.ButtonsAll.a12(1).Value;
                        app.ButtonsAll.a13(2).Items = {'1','3'};
                    elseif i == L
                        app.ButtonsAll.a7(L).Text = 'Value';
                        app.ButtonsAll.a3(L).Visible = 'on';
                        app.ButtonsAll.a4(L).Visible = 'off';
                        app.ButtonsAll.a5(L).Visible = 'off';
                        app.ButtonsAll.a6(L).Visible = 'off';
                        app.ButtonsAll.a7(L).Visible = 'on';
                        app.ButtonsAll.a8(L).Visible = 'off';
                        app.ButtonsAll.a9(L).Visible = 'off';
                        app.ButtonsAll.a10(L).Visible = 'on';
                        app.ButtonsAll.a12(L).Visible = 'on';
                        app.ButtonsAll.a13(L).Visible = 'on';
                        app.ButtonsAll.a1(L-1).Value = 'Lumped Element';
                        app.ButtonsAll.a2(L-1).Items = {'Capacitor','Variable Capacitor','Inductor','Mutual Inductor','Resistor'};
                        app.ButtonsAll.a2(L-1).Value = 'Mutual Inductor';
                        app.ButtonsAll.a12(L-1).Value = app.ButtonsAll.a12(L).Value;
                        app.ButtonsAll.a13(2).Items = {num2str(L),num2str(L-2)};
                    else
                        app.ButtonsAll.a7(str2double(app.ButtonsAll.a13(i).Value)).Text = 'Value';
                        app.ButtonsAll.a3(str2double(app.ButtonsAll.a13(i).Value)).Visible = 'on';
                        app.ButtonsAll.a4(str2double(app.ButtonsAll.a13(i).Value)).Visible = 'off';
                        app.ButtonsAll.a5(str2double(app.ButtonsAll.a13(i).Value)).Visible = 'off';
                        app.ButtonsAll.a6(str2double(app.ButtonsAll.a13(i).Value)).Visible = 'off';
                        app.ButtonsAll.a7(str2double(app.ButtonsAll.a13(i).Value)).Visible = 'on';
                        app.ButtonsAll.a8(str2double(app.ButtonsAll.a13(i).Value)).Visible = 'off';
                        app.ButtonsAll.a9(str2double(app.ButtonsAll.a13(i).Value)).Visible = 'off';
                        app.ButtonsAll.a10(str2double(app.ButtonsAll.a13(i).Value)).Visible = 'on';
                        app.ButtonsAll.a12(str2double(app.ButtonsAll.a13(i).Value)).Visible = 'on';
                        app.ButtonsAll.a13(str2double(app.ButtonsAll.a13(i).Value)).Visible = 'on';
                        app.ButtonsAll.a1(str2double(app.ButtonsAll.a13(i).Value)).Value = 'Lumped Element';
                        app.ButtonsAll.a2(str2double(app.ButtonsAll.a13(i).Value)).Items = {'Capacitor','Variable Capacitor','Inductor','Mutual Inductor','Resistor'};
                        app.ButtonsAll.a2(str2double(app.ButtonsAll.a13(i).Value)).Value = 'Mutual Inductor';
                        app.ButtonsAll.a12(str2double(app.ButtonsAll.a13(i).Value)).Value = app.ButtonsAll.a12(i).Value;
                        
                        if app.ButtonsAll.a13(i).Value < i
                            app.ButtonsAll.a13(str2double(app.ButtonsAll.a13(i).Value)).Items = {num2str(i),num2str(i+2)};
                        elseif app.ButtonsAll.a13(i).Value > i
                            app.ButtonsAll.a13(str2double(app.ButtonsAll.a13(i).Value)).Items = {num2str(i),num2str(i-2)};
                        end
                        app.ButtonsAll.a13(str2double(app.ButtonsAll.a13(i).Value)).Value = num2str(i);
                    end
                else
                    app.ButtonsAll.a7(i).Text = 'Value';
                    app.ButtonsAll.a3(i).Visible = 'on';
                    app.ButtonsAll.a4(i).Visible = 'off';
                    app.ButtonsAll.a5(i).Visible = 'off';
                    app.ButtonsAll.a6(i).Visible = 'off';
                    app.ButtonsAll.a7(i).Visible = 'on';
                    app.ButtonsAll.a8(i).Visible = 'off';
                    app.ButtonsAll.a9(i).Visible = 'off';
                    app.ButtonsAll.a10(i).Visible = 'off';
                    app.ButtonsAll.a12(i).Visible = 'off';
                    app.ButtonsAll.a13(i).Visible = 'off';
                end
            end
            
            % Create ValueChangedFcn callback
            function ValueChangedMutual(app,src,event,i)
                app.ButtonsAll.a12(str2double(app.ButtonsAll.a13(i).Value)).Value = app.ButtonsAll.a12(i).Value;
            end
            
            % Create ValueChangedFcn callback
            function ValueChangedMutualPort(app,src,event,i,L)
                app.ButtonsAll.a7(str2double(app.ButtonsAll.a13(i).Value)).Text = 'Value';
                app.ButtonsAll.a3(str2double(app.ButtonsAll.a13(i).Value)).Visible = 'on';
                app.ButtonsAll.a4(str2double(app.ButtonsAll.a13(i).Value)).Visible = 'off';
                app.ButtonsAll.a5(str2double(app.ButtonsAll.a13(i).Value)).Visible = 'off';
                app.ButtonsAll.a6(str2double(app.ButtonsAll.a13(i).Value)).Visible = 'off';
                app.ButtonsAll.a7(str2double(app.ButtonsAll.a13(i).Value)).Visible = 'on';
                app.ButtonsAll.a8(str2double(app.ButtonsAll.a13(i).Value)).Visible = 'off';
                app.ButtonsAll.a9(str2double(app.ButtonsAll.a13(i).Value)).Visible = 'off';
                app.ButtonsAll.a10(str2double(app.ButtonsAll.a13(i).Value)).Visible = 'on';
                app.ButtonsAll.a12(str2double(app.ButtonsAll.a13(i).Value)).Visible = 'on';
                app.ButtonsAll.a13(str2double(app.ButtonsAll.a13(i).Value)).Visible = 'on';
                app.ButtonsAll.a1(str2double(app.ButtonsAll.a13(i).Value)).Value = 'Lumped Element';
                app.ButtonsAll.a2(str2double(app.ButtonsAll.a13(i).Value)).Items = {'Capacitor','Variable Capacitor','Inductor','Mutual Inductor','Resistor'};
                app.ButtonsAll.a2(str2double(app.ButtonsAll.a13(i).Value)).Value = 'Mutual Inductor';
                app.ButtonsAll.a12(str2double(app.ButtonsAll.a13(i).Value)).Value = app.ButtonsAll.a12(i).Value;
                        
                if str2double(app.ButtonsAll.a13(i).Value) < str2double(i)
                    app.ButtonsAll.a13(str2double(app.ButtonsAll.a13(i).Value)).Items = {num2str(i),num2str(i-2)};
                elseif str2double(app.ButtonsAll.a13(i).Value) > str2double(i)
                    app.ButtonsAll.a13(str2double(app.ButtonsAll.a13(i).Value)).Items = {num2str(i),num2str(i+2)};
                end
                
                if strcmp(app.ButtonsAll.a13(i).Value,app.ButtonsAll.a13(i).Items(1))
                    i2 = app.ButtonsAll.a13(i).Items{2};
                else
                    i2 = app.ButtonsAll.a13(i).Items{1};
                end
                
                app.ButtonsAll.a1(str2double(i2)).Items = {'Feeding Port','Lumped Element'};
                app.ButtonsAll.a2(str2double(i2)).Items = {'Empty','Capacitor','Variable Capacitor','Inductor','Resistor'};
                app.ButtonsAll.a1(str2double(i2)).Value = 'Feeding Port';
                app.ButtonsAll.a2(str2double(i2)).Value = 'Empty';
                app.ButtonsAll.a3(str2double(i2)).Visible = 'off';
                app.ButtonsAll.a4(str2double(i2)).Visible = 'off';
                app.ButtonsAll.a5(str2double(i2)).Visible = 'off';
                app.ButtonsAll.a6(str2double(i2)).Visible = 'off';
                app.ButtonsAll.a7(str2double(i2)).Visible = 'off';
                app.ButtonsAll.a8(str2double(i2)).Visible = 'off';
                app.ButtonsAll.a9(str2double(i2)).Visible = 'off';
                app.ButtonsAll.a10(str2double(i2)).Visible = 'off';
                app.ButtonsAll.a12(str2double(i2)).Visible = 'off';
                if str2double(i2) == 1 
                    app.ButtonsAll.a13(str2double(i2)).Items = {'2'};
                elseif str2double(str2double(i2)) == L
                    app.ButtonsAll.a13(str2double(i2)).Items = {num2str(L-1)};
                else
                    app.ButtonsAll.a13(str2double(i2)).Items = {num2str(str2double(i2)-1),num2str(str2double(i2)+1)};
                end
                app.ButtonsAll.a13(str2double(i2)).Visible = 'off';
                
            end
            
            % Create ValueChangedFcn callback
            function ValueChangedInitial(app,src,event,i)
                value = app.ButtonsAll.a3(i).Value;
                app.ButtonsAll.a5(i).Limits = [0 value];
                app.ButtonsAll.a5(i).LowerLimitInclusive = 'off';
                app.ButtonsAll.a6(i).Limits = [value Inf];
                app.ButtonsAll.a6(i).LowerLimitInclusive = 'on';
            end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, value, marieapp)
            app.CallingApp = marieapp;
            
            if str2double(value)
                app.COIL_Set_Ports_app.Color = [0.302 0.749 0.9294];
            else
                app.COIL_Set_Ports_app.Color = [1 1 1];
            end
            
            a0 = uitabgroup(app.COIL_Set_Ports_app,'Position',[0,32,608,78]);
            coil_out = app.CallingApp.COIL;
            
            for i=1:length(coil_out.port)
                t = uitab(a0,'Title',['Port ' num2str(i)],'BackgroundColor',[1 1 1]);
                app.ButtonsAll.a3(i) = uieditfield(t,'numeric','Position',[328,23,51,22],'Value',1,'Limits', [0 Inf],'LowerLimitInclusive','off');
                app.ButtonsAll.a3(i).ValueChangedFcn = {@app.ValueChangedInitial,i};
                app.ButtonsAll.a4(i) = uieditfield(t,'numeric','Position',[386,23,51,22],'Value',1);
                app.ButtonsAll.a5(i) = uieditfield(t,'numeric','Position',[469,23,49,22],'Value',1,'Limits', [0 Inf],'LowerLimitInclusive','off');
                app.ButtonsAll.a6(i) = uieditfield(t,'numeric','Position',[555,23,49,22],'Value',1,'Limits', [0 Inf],'LowerLimitInclusive','off');
                app.ButtonsAll.a7(i) = uilabel(t,'Position',[228,27,95,15],'HorizontalAlignment','right','Text','Value');
                app.ButtonsAll.a8(i) = uilabel(t,'Position',[440,27,25,15],'HorizontalAlignment','right','Text','Min');
                app.ButtonsAll.a9(i) = uilabel(t,'Position',[523,27,28,15],'HorizontalAlignment','right','Text','Max');
                app.ButtonsAll.a10(i) = uilabel(t,'Position',[218,5,105,15],'HorizontalAlignment','right','Text','Mutual Value, Port');
                app.ButtonsAll.a12(i) = uieditfield(t,'numeric','Position',[328,1,51,22],'Value',1);
                app.ButtonsAll.a12(i).ValueChangedFcn = {@app.ValueChangedMutual,i};
                if i == 1 
                    app.ButtonsAll.a13(i) = uidropdown(t,'Position',[386,1,51,22],'Items',{'2'});
                elseif i == length(coil_out.port)
                    app.ButtonsAll.a13(i) = uidropdown(t,'Position',[386,1,51,22],'Items',{num2str(length(coil_out.port)-1)});
                else
                    app.ButtonsAll.a13(i) = uidropdown(t,'Position',[386,1,51,22],'Items',{num2str(i-1),num2str(i+1)});
                end
                app.ButtonsAll.a13(i).ValueChangedFcn = {@app.ValueChangedMutualPort,i,length(coil_out.port)};
                app.ButtonsAll.a1(i) = uidropdown(t,'Position',[9,23,100,22],'Items',{'Feeding Port','Lumped Element'});
                app.ButtonsAll.a1(i).ValueChangedFcn = {@app.ValueChangedPort,i,length(coil_out.port)};
                app.ButtonsAll.a2(i) = uidropdown(t,'Position',[117,23,100,22],'Items',{'Empty','Capacitor','Variable Capacitor','Inductor','Resistor'});
                app.ButtonsAll.a2(i).ValueChangedFcn = {@app.ValueChangedElement,i,length(coil_out.port)};
            end
            
            
            for i=1:length(coil_out.port)
                
                app.ButtonsAll.a3(i).Visible = 'off';
                app.ButtonsAll.a4(i).Visible = 'off';
                app.ButtonsAll.a5(i).Visible = 'off';
                app.ButtonsAll.a6(i).Visible = 'off';
                app.ButtonsAll.a7(i).Visible = 'off';
                app.ButtonsAll.a8(i).Visible = 'off';
                app.ButtonsAll.a9(i).Visible = 'off';
                app.ButtonsAll.a10(i).Visible = 'off';
                app.ButtonsAll.a12(i).Visible = 'off';
                app.ButtonsAll.a13(i).Visible = 'off';
                
            end
        
        end

        % Callback function: COIL_Set_Ports_app, ExitButton
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: UpdatePortsButton
        function UpdatePortsButtonPushed(app, event)
            coil_out = app.CallingApp.COIL;
            filename = strcat('./data/coils/lumped_elements_files/',extractBefore(coil_out.name,'_'),'.txt');
            fileID = fopen(filename,'w');
            fprintf(fileID,'%d \n',length(coil_out.port));
            for i=1:length(coil_out.port)
                if strcmp(app.ButtonsAll.a1(i).Value,'Lumped Element')
                    fprintf(fileID,'%d load ',i);
                    check_match = 0;
                else
                    fprintf(fileID,'%d feed ',i);
                    check_match = 1;
                end  
                if strcmp(app.ButtonsAll.a2(i).Value,'Variable Capacitor') && strcmp(app.ButtonsAll.a1(i).Value,'Feeding Port')
                    fprintf(fileID,'capacitor [] [] [] %d 0 %e %e %e \n',[check_match;app.ButtonsAll.a3(i).Value;app.ButtonsAll.a5(i).Value;app.ButtonsAll.a6(i).Value]);
                elseif strcmp(app.ButtonsAll.a2(i).Value,'Variable Capacitor') && strcmp(app.ButtonsAll.a1(i).Value,'Lumped Element')
                    fprintf(fileID,'capacitor [] [] [] 0 1 %e %e %e \n',[app.ButtonsAll.a3(i).Value;app.ButtonsAll.a5(i).Value;app.ButtonsAll.a6(i).Value]);
                elseif strcmp(app.ButtonsAll.a2(i).Value,'Mutual Inductor')
                    m_port = str2double(app.ButtonsAll.a13(i).Value);
                    fprintf(fileID,'mutual_inductor %e %e %d %d 0 [] [] [] \n',[app.ButtonsAll.a3(i).Value;app.ButtonsAll.a12(i).Value;m_port;check_match]);
                elseif strcmp(app.ButtonsAll.a2(i).Value,'Inductor')
                    fprintf(fileID,'inductor %e [] [] %d 0 [] [] [] \n',[app.ButtonsAll.a3(i).Value;check_match]);
                elseif strcmp(app.ButtonsAll.a2(i).Value,'Capacitor')
                    fprintf(fileID,'capacitor %e [] [] %d 0 [] [] [] \n',[app.ButtonsAll.a3(i).Value;check_match]);
                elseif strcmp(app.ButtonsAll.a2(i).Value,'Resistor')
                    fprintf(fileID,'resistor %e [] [] %d 0 [] [] [] \n',[app.ButtonsAll.a3(i).Value+1i*app.ButtonsAll.a4(i).Value;check_match]);
                elseif strcmp(app.ButtonsAll.a2(i).Value,'Empty')
                    fprintf(fileID,'resistor 50 [] [] %d 0 [] [] [] \n',check_match);
                end
            end
            fclose(fileID);
            
            coil_file_pure = coil_out.name;
            sie_settings.CoilPortsFile = strcat('lumped_elements_files/',extractBefore(coil_file_pure,'_'),'.txt');
            sie_settings.CoilMeshFile = strcat('msh_files/',coil_file_pure,'.msh');
            sie_settings.Coil_Path = './data/coils';
            coil_obj = src_geo.Coil_Loader(sie_settings);
            coil_out2 = coil_obj.load_coil();
            coil_out2.name = coil_file_pure;
            update_coil(app.CallingApp, coil_out2);
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COIL_Set_Ports_app = uifigure('Visible', 'off');
            app.COIL_Set_Ports_app.Name = 'UI Figure';
            app.COIL_Set_Ports_app.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton = uibutton(app.COIL_Set_Ports_app, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton.Text = 'Exit';
            app.UpdatePortsButton = uibutton(app.COIL_Set_Ports_app, 'push');
            app.UpdatePortsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePortsButtonPushed, true);
            app.UpdatePortsButton.Text = 'Update Ports';
            % Show the figure after all components are created
            app.COIL_Set_Ports_app.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_COIL_Set_Ports(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COIL_Set_Ports_app)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COIL_Set_Ports_app)
        end
    end
end