classdef app_RHBM_4comp_cuboid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RHBM_4_comp_cuboid_app          matlab.ui.Figure
        ExitButton                      matlab.ui.control.Button
        yEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        zEditField                      matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        EditField_4                     matlab.ui.control.EditField
        e_rEditField                    matlab.ui.control.NumericEditField
        Create4CompartmentCuboidButton  matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        resEditField                    matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.EditField
        EditField_7                     matlab.ui.control.EditField
        EditField_8                     matlab.ui.control.EditField
        EditField_9                     matlab.ui.control.EditField
        xEditField_2                    matlab.ui.control.NumericEditField
        xEditFieldLabel_2               matlab.ui.control.Label
        yEditField_2                    matlab.ui.control.NumericEditField
        yEditField_2Label               matlab.ui.control.Label
        zEditField_2                    matlab.ui.control.NumericEditField
        zEditField_2Label               matlab.ui.control.Label
        xEditFieldLabel_3               matlab.ui.control.Label
        e_rEditField_2                  matlab.ui.control.NumericEditField
        xEditFieldLabel_4               matlab.ui.control.Label
        e_rEditField_3                  matlab.ui.control.NumericEditField
        xEditFieldLabel_5               matlab.ui.control.Label
        e_rEditField_4                  matlab.ui.control.NumericEditField
        xEditFieldLabel_6               matlab.ui.control.Label
        e_rEditField_5                  matlab.ui.control.NumericEditField
        xEditFieldLabel_7               matlab.ui.control.Label
        e_rEditField_6                  matlab.ui.control.NumericEditField
        xEditFieldLabel_8               matlab.ui.control.Label
        e_rEditField_7                  matlab.ui.control.NumericEditField
        xEditFieldLabel_9               matlab.ui.control.Label
        e_rEditField_8                  matlab.ui.control.NumericEditField
        xEditFieldLabel_10              matlab.ui.control.Label
        e_rEditField_9                  matlab.ui.control.NumericEditField
        xEditFieldLabel_11              matlab.ui.control.Label
        e_rEditField_10                 matlab.ui.control.NumericEditField
        xEditFieldLabel_12              matlab.ui.control.Label
        e_rEditField_11                 matlab.ui.control.NumericEditField
        xEditFieldLabel_13              matlab.ui.control.Label
        e_rEditField_12                 matlab.ui.control.NumericEditField
        xEditFieldLabel_14              matlab.ui.control.Label
    end


    properties (Access = private)
        CallingApp   % Main app       
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, value, marieapp)
            if str2double(value)
                app.RHBM_4_comp_cuboid_app.Color = [0.302 0.749 0.9294];
            else
                app.RHBM_4_comp_cuboid_app.Color = [1 1 1];
            end
            app.CallingApp = marieapp;
        end

        % Callback function: ExitButton, RHBM_4_comp_cuboid_app
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: Create4CompartmentCuboidButton
        function Create4CompartmentCuboidButtonPushed(app, event)
            Cnt = [app.xEditField.Value app.yEditField.Value app.zEditField.Value];
            Len = [app.xEditField_2.Value app.yEditField_2.Value app.zEditField_2.Value];
            e_r = [app.e_rEditField.Value app.e_rEditField_2.Value app.e_rEditField_3.Value app.e_rEditField_4.Value];
            s_e = [app.e_rEditField_5.Value app.e_rEditField_6.Value app.e_rEditField_7.Value app.e_rEditField_8.Value];
            dens = [app.e_rEditField_9.Value app.e_rEditField_10.Value app.e_rEditField_11.Value app.e_rEditField_12.Value];
            res = app.resEditField.Value;
            rhbm_out = app.CallingApp.obj_rhbm_generator.RHBM_4comp_cuboid(res,Len,Cnt,e_r,s_e,dens);
            update_body(app.CallingApp, rhbm_out);
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RHBM_4_comp_cuboid_app = uifigure;
            app.RHBM_4_comp_cuboid_app.Name = 'UI Figure';
            app.RHBM_4_comp_cuboid_app.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton = uibutton(app.RHBM_4_comp_cuboid_app, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton.Text = 'Exit';
            app.yEditField = uieditfield(app.RHBM_4_comp_cuboid_app, 'numeric');
            app.yEditFieldLabel = uilabel(app.RHBM_4_comp_cuboid_app);
            app.yEditFieldLabel.Text = 'y';
            app.zEditField = uieditfield(app.RHBM_4_comp_cuboid_app, 'numeric');
            app.zEditFieldLabel = uilabel(app.RHBM_4_comp_cuboid_app);
            app.zEditFieldLabel.Text = 'z';
            app.xEditField = uieditfield(app.RHBM_4_comp_cuboid_app, 'numeric');
            app.xEditFieldLabel = uilabel(app.RHBM_4_comp_cuboid_app);
            app.xEditFieldLabel.Text = 'x';
            app.EditField = uieditfield(app.RHBM_4_comp_cuboid_app, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Center';
            app.EditField_4 = uieditfield(app.RHBM_4_comp_cuboid_app, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Value = 'Relative Permittivity';
            app.e_rEditField = uieditfield(app.RHBM_4_comp_cuboid_app, 'numeric');
            app.e_rEditField.Value = 1;
            app.Create4CompartmentCuboidButton = uibutton(app.RHBM_4_comp_cuboid_app, 'push');
            app.Create4CompartmentCuboidButton.ButtonPushedFcn = createCallbackFcn(app, @Create4CompartmentCuboidButtonPushed, true);
            app.Create4CompartmentCuboidButton.Text = {'Create '; '4 Compartment '; 'Cuboid'};
            app.Button = uibutton(app.RHBM_4_comp_cuboid_app, 'push');
            app.Button.Icon = 'Marie2logo.jpg';
            app.Button.Text = '';
            app.resEditField = uieditfield(app.RHBM_4_comp_cuboid_app, 'numeric');
            app.resEditField.Value = 0.004;
            app.EditField_5 = uieditfield(app.RHBM_4_comp_cuboid_app, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_5.Value = 'Resolution';
            app.EditField_7 = uieditfield(app.RHBM_4_comp_cuboid_app, 'text');
            app.EditField_7.Editable = 'off';
            app.EditField_7.Value = 'Conductivity';
            app.EditField_8 = uieditfield(app.RHBM_4_comp_cuboid_app, 'text');
            app.EditField_8.Editable = 'off';
            app.EditField_8.Value = 'Density';
            app.EditField_9 = uieditfield(app.RHBM_4_comp_cuboid_app, 'text');
            app.EditField_9.Editable = 'off';
            app.EditField_9.Value = 'Length';
            app.xEditField_2 = uieditfield(app.RHBM_4_comp_cuboid_app, 'numeric');
            app.xEditField_2.Value = 0.1;
            app.xEditFieldLabel_2 = uilabel(app.RHBM_4_comp_cuboid_app);
            app.xEditFieldLabel_2.Text = 'x';
            app.yEditField_2 = uieditfield(app.RHBM_4_comp_cuboid_app, 'numeric');
            app.yEditField_2.Value = 0.1;
            app.yEditField_2Label = uilabel(app.RHBM_4_comp_cuboid_app);
            app.yEditField_2Label.Text = 'y';
            app.zEditField_2 = uieditfield(app.RHBM_4_comp_cuboid_app, 'numeric');
            app.zEditField_2.Value = 0.1;
            app.zEditField_2Label = uilabel(app.RHBM_4_comp_cuboid_app);
            app.zEditField_2Label.Text = 'z';
            app.xEditFieldLabel_3 = uilabel(app.RHBM_4_comp_cuboid_app);
            app.xEditFieldLabel_3.Text = 'bl';
            app.e_rEditField_2 = uieditfield(app.RHBM_4_comp_cuboid_app, 'numeric');
            app.e_rEditField_2.Value = 1;
            app.xEditFieldLabel_4 = uilabel(app.RHBM_4_comp_cuboid_app);
            app.xEditFieldLabel_4.Text = 'fl';
            app.e_rEditField_3 = uieditfield(app.RHBM_4_comp_cuboid_app, 'numeric');
            app.e_rEditField_3.Value = 1;
            app.xEditFieldLabel_5 = uilabel(app.RHBM_4_comp_cuboid_app);
            app.xEditFieldLabel_5.Text = 'br';
            app.e_rEditField_4 = uieditfield(app.RHBM_4_comp_cuboid_app, 'numeric');
            app.e_rEditField_4.Value = 1;
            app.xEditFieldLabel_6 = uilabel(app.RHBM_4_comp_cuboid_app);
            app.xEditFieldLabel_6.Text = 'fr';
            app.e_rEditField_5 = uieditfield(app.RHBM_4_comp_cuboid_app, 'numeric');
            app.xEditFieldLabel_7 = uilabel(app.RHBM_4_comp_cuboid_app);
            app.xEditFieldLabel_7.Text = 'bl';
            app.e_rEditField_6 = uieditfield(app.RHBM_4_comp_cuboid_app, 'numeric');
            app.xEditFieldLabel_8 = uilabel(app.RHBM_4_comp_cuboid_app);
            app.xEditFieldLabel_8.Text = 'fl';
            app.e_rEditField_7 = uieditfield(app.RHBM_4_comp_cuboid_app, 'numeric');
            app.xEditFieldLabel_9 = uilabel(app.RHBM_4_comp_cuboid_app);
            app.xEditFieldLabel_9.Text = 'br';
            app.e_rEditField_8 = uieditfield(app.RHBM_4_comp_cuboid_app, 'numeric');
            app.xEditFieldLabel_10 = uilabel(app.RHBM_4_comp_cuboid_app);
            app.xEditFieldLabel_10.Text = 'fr';
            app.e_rEditField_9 = uieditfield(app.RHBM_4_comp_cuboid_app, 'numeric');
            app.xEditFieldLabel_11 = uilabel(app.RHBM_4_comp_cuboid_app);
            app.xEditFieldLabel_11.Text = 'bl';
            app.e_rEditField_10 = uieditfield(app.RHBM_4_comp_cuboid_app, 'numeric');
            app.xEditFieldLabel_12 = uilabel(app.RHBM_4_comp_cuboid_app);
            app.xEditFieldLabel_12.Text = 'fl';
            app.e_rEditField_11 = uieditfield(app.RHBM_4_comp_cuboid_app, 'numeric');
            app.xEditFieldLabel_13 = uilabel(app.RHBM_4_comp_cuboid_app);
            app.xEditFieldLabel_13.Text = 'br';
            app.e_rEditField_12 = uieditfield(app.RHBM_4_comp_cuboid_app, 'numeric');
            app.xEditFieldLabel_14 = uilabel(app.RHBM_4_comp_cuboid_app);
            app.xEditFieldLabel_14.Text = 'fr';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_RHBM_4comp_cuboid(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RHBM_4_comp_cuboid_app)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RHBM_4_comp_cuboid_app)
        end
    end
end