classdef app_RHBM_RHBM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RHBM_RHBM_app                  matlab.ui.Figure
        ExitButton                     matlab.ui.control.Button
        LoadRHBMButton                 matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        SectionofBodyDropDownLabel     matlab.ui.control.Label
        SectionofBodyDropDown          matlab.ui.control.DropDown
        ResolutionDropDownLabel        matlab.ui.control.Label
        ResolutionDropDown             matlab.ui.control.DropDown
        VirtualFamilyModelDropDownLabel  matlab.ui.control.Label
        VirtualFamilyModel             matlab.ui.control.DropDown
        TeslaDropDownLabel             matlab.ui.control.Label
        TeslaDropDown                  matlab.ui.control.DropDown
        EditField                      matlab.ui.control.EditField
        EditField_2                    matlab.ui.control.EditField
        PositionDropDownLabel          matlab.ui.control.Label
        PositionDropDown               matlab.ui.control.DropDown
        RelativePermittivityEditFieldLabel  matlab.ui.control.Label
        RelativePermittivityEditField  matlab.ui.control.NumericEditField
        ConductivityEditFieldLabel     matlab.ui.control.Label
        ConductivityEditField          matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        rhbmp
        CallingApp   % Main app       
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, value, marieapp)
            if str2double(value)
                app.RHBM_RHBM_app.Color = [0.302 0.749 0.9294];
            else
                app.RHBM_RHBM_app.Color = [1 1 1];
            end
            app.LoadRHBMButton.Enable = 'off';
            app.CallingApp = marieapp;
        end

        % Callback function: ExitButton, RHBM_RHBM_app
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: ConductivityEditField, 
        % PositionDropDown, RelativePermittivityEditField, 
        % ResolutionDropDown, SectionofBodyDropDown, TeslaDropDown, 
        % VirtualFamilyModel
        function VirtualFamilyModelValueChanged(app, event)
            value_1 = app.VirtualFamilyModel.Value;
            value_2 = app.ResolutionDropDown.Value;
            value_3 = app.TeslaDropDown.Value;
            value_4 = app.SectionofBodyDropDown.Value;
            if strcmp(value_1,'No model selected')
                app.LoadRHBMButton.Enable = 'off';
                f1 = false;
            elseif strcmp(value_1,'Billie')
                s1 = 'billie';
                f1 = true;
            elseif strcmp(value_1,'Duke')
                s1 = 'duke';
                f1 = true;
            elseif strcmp(value_1,'Ella')
                s1 = 'ella';
                f1 = true;
            end
            
            if strcmp(value_2,'No resolution selected')
                app.LoadRHBMButton.Enable = 'off';
                f2 = false;
            elseif strcmp(value_2,'5mm')
                s2 = '_5_mm_';
                f2 = true;
            elseif strcmp(value_2,'2mm')
                s2 = '_2_mm_';
                f2 = true;
            end
            
            if strcmp(value_3,'No value selected')
                app.LoadRHBMButton.Enable = 'off';
                f3 = false;
            elseif strcmp(value_3,'1.5 Tesla')
                s3 = '63.87MHz.mat';
                f3 = true;
            elseif strcmp(value_3,'3 Tesla')
                s3 = '127.74MHz.mat';
                f3 = true;
            elseif strcmp(value_3,'7 Tesla')
                s3 = '298MHz.mat';
                f3 = true;
            end
            
            if f1 && f2 && f3 
                app.SectionofBodyDropDown.Enable = 'on';
            else
                app.SectionofBodyDropDown.Enable = 'off';
            end
                
            
            if strcmp(value_4,'No section selected')
                app.LoadRHBMButton.Enable = 'off';
                app.PositionDropDown.Enable = 'off';
                app.RelativePermittivityEditField.Enable = 'off';
                app.ConductivityEditField.Enable = 'off';
                f4 = false;
            else
                if strcmp(value_4,'Head with Pad')
                    app.PositionDropDown.Enable = 'on';
                    app.RelativePermittivityEditField.Enable = 'on';
                    app.ConductivityEditField.Enable = 'on';
                else
                    app.PositionDropDown.Enable = 'off';
                    app.RelativePermittivityEditField.Enable = 'off';
                    app.ConductivityEditField.Enable = 'off';
                end
                f4 = true;
            end
            
            if f1 && f2 && f3 && f4
                s = strcat(s1,s2,s3);
                app.rhbmp.s = s;
                app.rhbmp.s2.name = value_4;
                if strcmp(value_4,'Head with Pad')
                    app.rhbmp.s2.pos = app.PositionDropDown.Value;
                    app.rhbmp.s2.er = app.RelativePermittivityEditField.Value;
                    app.rhbmp.s2.se = app.ConductivityEditField.Value;
                end
                app.LoadRHBMButton.Enable = 'on';
            end
        end

        % Button pushed function: LoadRHBMButton
        function LoadRHBMButtonPushed(app, event)
            if strcmp(app.rhbmp.s2.name,'Head with Pad')
                rhbm_out = app.CallingApp.obj_rhbm_generator.RHBM_RHBM_pad(app.rhbmp.s,app.rhbmp.s2.name,app.rhbmp.s2.pos,app.rhbmp.s2.er,app.rhbmp.s2.se);
            else
                rhbm_out = app.CallingApp.obj_rhbm_generator.RHBM_RHBM(app.rhbmp.s,app.rhbmp.s2.name);
            end
            update_body(app.CallingApp, rhbm_out);
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RHBM_RHBM_app = uifigure;
            app.RHBM_RHBM_app.Name = 'UI Figure';
            app.RHBM_RHBM_app.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton = uibutton(app.RHBM_RHBM_app, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton.Text = 'Exit';
            app.LoadRHBMButton = uibutton(app.RHBM_RHBM_app, 'push');
            app.LoadRHBMButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRHBMButtonPushed, true);
            app.LoadRHBMButton.Text = 'Load RHBM';
            app.Button = uibutton(app.RHBM_RHBM_app, 'push');
            app.Button.Icon = 'Marie2logo.jpg';
            app.Button.Text = '';
            app.SectionofBodyDropDownLabel = uilabel(app.RHBM_RHBM_app);
            app.SectionofBodyDropDownLabel.Text = 'Section of Body';
            app.SectionofBodyDropDown = uidropdown(app.RHBM_RHBM_app);
            app.SectionofBodyDropDown.Items = {'No section selected', 'Full Body', 'Head', 'Head and Shoulders', 'Left Leg', 'Right Leg', 'Head with Pad'};
            app.SectionofBodyDropDown.ValueChangedFcn = createCallbackFcn(app, @VirtualFamilyModelValueChanged, true);
            app.SectionofBodyDropDown.Enable = 'off';
            app.SectionofBodyDropDown.Value = 'No section selected';
            app.ResolutionDropDownLabel = uilabel(app.RHBM_RHBM_app);
            app.ResolutionDropDownLabel.Text = 'Resolution';
            app.ResolutionDropDown = uidropdown(app.RHBM_RHBM_app);
            app.ResolutionDropDown.Items = {'No resolution selected', '5mm', '2mm'};
            app.ResolutionDropDown.ValueChangedFcn = createCallbackFcn(app, @VirtualFamilyModelValueChanged, true);
            app.ResolutionDropDown.Value = 'No resolution selected';
            app.VirtualFamilyModelDropDownLabel = uilabel(app.RHBM_RHBM_app);
            app.VirtualFamilyModelDropDownLabel.Text = 'Virtual Family Model';
            app.VirtualFamilyModel = uidropdown(app.RHBM_RHBM_app);
            app.VirtualFamilyModel.Items = {'No model selected', 'Billie', 'Duke', 'Ella'};
            app.VirtualFamilyModel.ValueChangedFcn = createCallbackFcn(app, @VirtualFamilyModelValueChanged, true);
            app.VirtualFamilyModel.Value = 'No model selected';
            app.TeslaDropDownLabel = uilabel(app.RHBM_RHBM_app);
            app.TeslaDropDownLabel.Text = 'Tesla';
            app.TeslaDropDown = uidropdown(app.RHBM_RHBM_app);
            app.TeslaDropDown.Items = {'No value selected', '1.5 Tesla', '3 Tesla', '7 Tesla'};
            app.TeslaDropDown.ValueChangedFcn = createCallbackFcn(app, @VirtualFamilyModelValueChanged, true);
            app.TeslaDropDown.Value = 'No value selected';
            app.EditField = uieditfield(app.RHBM_RHBM_app, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Note (1): The electric properties of the body change depending on the main magnetic field strength';
            app.EditField_2 = uieditfield(app.RHBM_RHBM_app, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = 'Note (2): The option for dielectric pad is available only for head models';
            app.PositionDropDownLabel = uilabel(app.RHBM_RHBM_app);
            app.PositionDropDownLabel.Text = 'Position';
            app.PositionDropDown = uidropdown(app.RHBM_RHBM_app);
            app.PositionDropDown.Items = {'Left', 'Right'};
            app.PositionDropDown.ValueChangedFcn = createCallbackFcn(app, @VirtualFamilyModelValueChanged, true);
            app.PositionDropDown.Enable = 'off';
            app.PositionDropDown.Value = 'Left';
            app.RelativePermittivityEditFieldLabel = uilabel(app.RHBM_RHBM_app);
            app.RelativePermittivityEditFieldLabel.Text = 'Relative Permittivity';
            app.RelativePermittivityEditField = uieditfield(app.RHBM_RHBM_app, 'numeric');
            app.RelativePermittivityEditField.ValueChangedFcn = createCallbackFcn(app, @VirtualFamilyModelValueChanged, true);
            app.RelativePermittivityEditField.Enable = 'off';
            app.RelativePermittivityEditField.Value = 3;
            app.ConductivityEditFieldLabel = uilabel(app.RHBM_RHBM_app);
            app.ConductivityEditFieldLabel.Text = 'Conductivity';
            app.ConductivityEditField = uieditfield(app.RHBM_RHBM_app, 'numeric');
            app.ConductivityEditField.ValueChangedFcn = createCallbackFcn(app, @VirtualFamilyModelValueChanged, true);
            app.ConductivityEditField.Enable = 'off';
            app.ConductivityEditField.Value = 30;
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_RHBM_RHBM(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RHBM_RHBM_app)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RHBM_RHBM_app)
        end
    end
end