classdef app_RHBM_cylinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RHBM_cylinder_app     matlab.ui.Figure
        ExitButton            matlab.ui.control.Button
        yEditField            matlab.ui.control.NumericEditField
        yEditFieldLabel       matlab.ui.control.Label
        zEditField            matlab.ui.control.NumericEditField
        zEditFieldLabel       matlab.ui.control.Label
        xEditField            matlab.ui.control.NumericEditField
        xEditFieldLabel       matlab.ui.control.Label
        EditField             matlab.ui.control.EditField
        EditField_3           matlab.ui.control.EditField
        RadEditField          matlab.ui.control.NumericEditField
        LenEditField          matlab.ui.control.NumericEditField
        EditField_4           matlab.ui.control.EditField
        s_eEditField          matlab.ui.control.NumericEditField
        densEditField         matlab.ui.control.NumericEditField
        e_rEditField          matlab.ui.control.NumericEditField
        CreateCylinderButton  matlab.ui.control.Button
        Button                matlab.ui.control.Button
        resEditField          matlab.ui.control.NumericEditField
        EditField_5           matlab.ui.control.EditField
        EditField_6           matlab.ui.control.EditField
        EditField_7           matlab.ui.control.EditField
        EditField_8           matlab.ui.control.EditField
    end


    properties (Access = private)
        CallingApp   % Main app       
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, value, marieapp)
            if str2double(value)
                app.RHBM_cylinder_app.Color = [0.302 0.749 0.9294];
            else
                app.RHBM_cylinder_app.Color = [1 1 1];
            end
            app.CallingApp = marieapp;
        end

        % Callback function: ExitButton, RHBM_cylinder_app
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: CreateCylinderButton
        function CreateCylinderButtonPushed(app, event)
            Cnt = [app.xEditField.Value app.yEditField.Value app.zEditField.Value];
            Rad = app.RadEditField.Value;
            Len = app.LenEditField.Value;
            e_r = app.e_rEditField.Value;
            s_e = app.s_eEditField.Value;
            dens = app.densEditField.Value; 
            res = app.resEditField.Value;
            rhbm_out = app.CallingApp.obj_rhbm_generator.RHBM_cylinder(res,Rad,Len,Cnt,e_r,s_e,dens);
            update_body(app.CallingApp, rhbm_out);
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RHBM_cylinder_app = uifigure;
            app.RHBM_cylinder_app.Name = 'UI Figure';
            app.RHBM_cylinder_app.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton = uibutton(app.RHBM_cylinder_app, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton.Text = 'Exit';
            app.yEditField = uieditfield(app.RHBM_cylinder_app, 'numeric');
            app.yEditFieldLabel = uilabel(app.RHBM_cylinder_app);
            app.yEditFieldLabel.Text = 'y';
            app.zEditField = uieditfield(app.RHBM_cylinder_app, 'numeric');
            app.zEditFieldLabel = uilabel(app.RHBM_cylinder_app);
            app.zEditFieldLabel.Text = 'z';
            app.xEditField = uieditfield(app.RHBM_cylinder_app, 'numeric');
            app.xEditFieldLabel = uilabel(app.RHBM_cylinder_app);
            app.xEditFieldLabel.Text = 'x';
            app.EditField = uieditfield(app.RHBM_cylinder_app, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Center';
            app.EditField_3 = uieditfield(app.RHBM_cylinder_app, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Value = 'Radius';
            app.RadEditField = uieditfield(app.RHBM_cylinder_app, 'numeric');
            app.RadEditField.Value = 0.061;
            app.LenEditField = uieditfield(app.RHBM_cylinder_app, 'numeric');
            app.LenEditField.Value = 0.25;
            app.EditField_4 = uieditfield(app.RHBM_cylinder_app, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Value = 'Relative Permittivity';
            app.s_eEditField = uieditfield(app.RHBM_cylinder_app, 'numeric');
            app.densEditField = uieditfield(app.RHBM_cylinder_app, 'numeric');
            app.e_rEditField = uieditfield(app.RHBM_cylinder_app, 'numeric');
            app.e_rEditField.Value = 1;
            app.CreateCylinderButton = uibutton(app.RHBM_cylinder_app, 'push');
            app.CreateCylinderButton.ButtonPushedFcn = createCallbackFcn(app, @CreateCylinderButtonPushed, true);
            app.CreateCylinderButton.Text = 'Create Cylinder';
            app.Button = uibutton(app.RHBM_cylinder_app, 'push');
            app.Button.Icon = 'Marie2logo.jpg';
            app.Button.Text = '';
            app.resEditField = uieditfield(app.RHBM_cylinder_app, 'numeric');
            app.resEditField.Value = 0.004;
            app.EditField_5 = uieditfield(app.RHBM_cylinder_app, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_5.Value = 'Resolution';
            app.EditField_6 = uieditfield(app.RHBM_cylinder_app, 'text');
            app.EditField_6.Editable = 'off';
            app.EditField_6.Value = 'Length';
            app.EditField_7 = uieditfield(app.RHBM_cylinder_app, 'text');
            app.EditField_7.Editable = 'off';
            app.EditField_7.Value = 'Conductivity';
            app.EditField_8 = uieditfield(app.RHBM_cylinder_app, 'text');
            app.EditField_8.Editable = 'off';
            app.EditField_8.Value = 'Density';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_RHBM_cylinder(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RHBM_cylinder_app)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RHBM_cylinder_app)
        end
    end
end