classdef app_RHBM_displacement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RHBM_displacement_app  matlab.ui.Figure
        ExitButton             matlab.ui.control.Button
        yEditField             matlab.ui.control.NumericEditField
        yEditFieldLabel        matlab.ui.control.Label
        zEditField             matlab.ui.control.NumericEditField
        zEditFieldLabel        matlab.ui.control.Label
        xEditField             matlab.ui.control.NumericEditField
        xEditFieldLabel        matlab.ui.control.Label
        EditField              matlab.ui.control.EditField
        DisplaceRHBMButton     matlab.ui.control.Button
        Button                 matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp   % Main app   
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, value, marieapp)
            if str2double(value)
                app.RHBM_displacement_app.Color = [0.302 0.749 0.9294];
            else
                app.RHBM_displacement_app.Color = [1 1 1];
            end
            app.CallingApp = marieapp;
        end

        % Callback function: ExitButton, RHBM_displacement_app
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: DisplaceRHBMButton
        function DisplaceRHBMButtonPushed(app, event)
            Cnt = [app.xEditField.Value app.yEditField.Value app.zEditField.Value];
            rhbm_out = app.CallingApp.obj_rhbm_generator.RHBM_displacement(Cnt,app.CallingApp.RHBM);
            update_body(app.CallingApp, rhbm_out);
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RHBM_displacement_app = uifigure;
            app.RHBM_displacement_app.Name = 'UI Figure';
            app.RHBM_displacement_app.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton = uibutton(app.RHBM_displacement_app, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton.Text = 'Exit';
            app.yEditField = uieditfield(app.RHBM_displacement_app, 'numeric');
            app.yEditFieldLabel = uilabel(app.RHBM_displacement_app);
            app.yEditFieldLabel.Text = 'y';
            app.zEditField = uieditfield(app.RHBM_displacement_app, 'numeric');
            app.zEditFieldLabel = uilabel(app.RHBM_displacement_app);
            app.zEditFieldLabel.Text = 'z';
            app.xEditField = uieditfield(app.RHBM_displacement_app, 'numeric');
            app.xEditFieldLabel = uilabel(app.RHBM_displacement_app);
            app.xEditFieldLabel.Text = 'x';
            app.EditField = uieditfield(app.RHBM_displacement_app, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Displace';
            app.DisplaceRHBMButton = uibutton(app.RHBM_displacement_app, 'push');
            app.DisplaceRHBMButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaceRHBMButtonPushed, true);
            app.DisplaceRHBMButton.Text = 'Displace RHBM';
            app.Button = uibutton(app.RHBM_displacement_app, 'push');
            app.Button.Icon = 'Marie2logo.jpg';
            app.Button.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_RHBM_displacement(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RHBM_displacement_app)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RHBM_displacement_app)
        end
    end
end