classdef app_generate_advanced_input < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        generate_advanced_input_app  matlab.ui.Figure
        ExitButton_2                 matlab.ui.control.Button
        EditField                    matlab.ui.control.EditField
        pwx                          matlab.ui.control.DropDown
        viesolver1                   matlab.ui.control.DropDown
        pu                           matlab.ui.control.DropDown
        freq2                        matlab.ui.control.NumericEditField
        GenerateInputsButton         matlab.ui.control.Button
        viesolver                    matlab.ui.control.DropDown
        viesolver3                   matlab.ui.control.NumericEditField
        viesolver2                   matlab.ui.control.NumericEditField
        tucker                       matlab.ui.control.DropDown
        siesolver                    matlab.ui.control.DropDown
        siesolver1                   matlab.ui.control.DropDown
        siesolver3                   matlab.ui.control.NumericEditField
        siesolver2                   matlab.ui.control.NumericEditField
        vsiesolver1                  matlab.ui.control.DropDown
        vsiesolver3                  matlab.ui.control.NumericEditField
        vsiesolver2                  matlab.ui.control.NumericEditField
        expvoxels                    matlab.ui.control.DropDown
        freqLabel                    matlab.ui.control.Label
        PWXLabel                     matlab.ui.control.Label
        VIEsolverLabel               matlab.ui.control.Label
        VIEtoreranceLabel            matlab.ui.control.Label
        Tucker                       matlab.ui.control.Label
        DeviceLabel                  matlab.ui.control.Label
        SIESolverLabel               matlab.ui.control.Label
        VSIESolverGMRESLabel         matlab.ui.control.Label
        ExpansionVoxelsLabel         matlab.ui.control.Label
        IterationsLabel              matlab.ui.control.Label
        RestartsLabel                matlab.ui.control.Label
        IterationsLabel_2            matlab.ui.control.Label
        IterationsLabel_3            matlab.ui.control.Label
        ToleranceLabel_2             matlab.ui.control.Label
        ToleranceLabel_3             matlab.ui.control.Label
        RestartsLabel_2              matlab.ui.control.Label
        RestartsLabel_3              matlab.ui.control.Label
        EditField_5                  matlab.ui.control.EditField
        EditField_6                  matlab.ui.control.EditField
        EditField_7                  matlab.ui.control.EditField
        EditField_8                  matlab.ui.control.EditField
        freq1                        matlab.ui.control.NumericEditField
        freqLabel_2                  matlab.ui.control.Label
        freq3                        matlab.ui.control.NumericEditField
        freqLabel_3                  matlab.ui.control.Label
        EditField_11                 matlab.ui.control.EditField
        VoxelnearinteractionsEditFieldLabel  matlab.ui.control.Label
        vienear                      matlab.ui.control.NumericEditField
        VoxelmediuminteractionsEditFieldLabel  matlab.ui.control.Label
        viemedium                    matlab.ui.control.NumericEditField
        VoxelfarinteractionsEditFieldLabel  matlab.ui.control.Label
        viefar                       matlab.ui.control.NumericEditField
        CouplingVIEquadraturepointsEditFieldLabel  matlab.ui.control.Label
        vsievie                      matlab.ui.control.NumericEditField
        CouplingSIEquadraturepointsEditFieldLabel  matlab.ui.control.Label
        vsiesie                      matlab.ui.control.NumericEditField
        mode                         matlab.ui.control.DropDown
        VSIEsolvermodeLabel          matlab.ui.control.Label
    end


    properties (Access = private)
        INP
        CallingApp   % Main app 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, value, marieapp)
            if str2double(value)
                app.generate_advanced_input_app.Color = [0.302 0.749 0.9294];
            else
                app.generate_advanced_input_app.Color = [1 1 1];
            end
            
            app.freq3.Visible = 'off';
            app.freqLabel_3.Visible = 'off';
            
            
            app.siesolver1.Visible = 'off';
            app.siesolver3.Visible = 'off';
            app.siesolver2.Visible = 'off';
            app.RestartsLabel_2.Visible = 'off';
            app.IterationsLabel_2.Visible = 'off';
            app.ToleranceLabel_2.Visible = 'off';
            
            app.CallingApp = marieapp;
        end

        % Callback function: ExitButton_2, generate_advanced_input_app
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: siesolver
        function siesolverValueChanged(app, event)
            value = app.siesolver.Value;
            if strcmp(value,'LU')
                app.siesolver1.Enable = 'off';
                app.siesolver3.Enable = 'off';
                app.siesolver2.Enable = 'off';
                app.IterationsLabel_2.Enable = 'off';
                app.ToleranceLabel_2.Enable = 'off';
                app.RestartsLabel_2.Enable = 'off';
                app.siesolver1.Visible = 'off';
                app.siesolver3.Visible = 'off';
                app.siesolver2.Visible = 'off';
                app.IterationsLabel_2.Visible = 'off';
                app.ToleranceLabel_2.Visible = 'off';
                app.RestartsLabel_2.Visible = 'off';
            elseif strcmp(value,'GMRES')
                app.siesolver1.Enable = 'on';
                app.siesolver3.Enable = 'on';
                app.siesolver2.Enable = 'on';
                app.IterationsLabel_2.Enable = 'on';
                app.ToleranceLabel_2.Enable = 'on';
                app.RestartsLabel_2.Enable = 'on';
                app.siesolver1.Visible = 'on';
                app.siesolver3.Visible = 'on';
                app.siesolver2.Visible = 'on';
                app.IterationsLabel_2.Visible = 'on';
                app.ToleranceLabel_2.Visible = 'on';
                app.RestartsLabel_2.Visible = 'on';
            elseif strcmp(value,'BICGSTAB')
                app.siesolver1.Enable = 'on';
                app.siesolver3.Enable = 'off';
                app.siesolver2.Enable = 'on';
                app.IterationsLabel_2.Enable = 'on';
                app.ToleranceLabel_2.Enable = 'on';
                app.RestartsLabel_2.Enable = 'off';
                app.siesolver1.Visible = 'on';
                app.siesolver3.Visible = 'off';
                app.siesolver2.Visible = 'on';
                app.IterationsLabel_2.Visible = 'on';
                app.ToleranceLabel_2.Visible = 'on';
                app.RestartsLabel_2.Visible = 'off';
            end
        end

        % Value changed function: viesolver
        function viesolverValueChanged(app, event)
            value = app.viesolver.Value;
            if strcmp(value,'GMRES')
                app.viesolver3.Enable = 'on';
                app.RestartsLabel.Enable = 'on';
                app.viesolver3.Visible = 'on';
                app.RestartsLabel.Visible = 'on';
            elseif strcmp(value,'BICGSTAB')
                app.viesolver3.Enable = 'off';
                app.RestartsLabel.Enable = 'off';
                app.viesolver3.Visible = 'off';
                app.RestartsLabel.Visible = 'off';
            end
        end

        % Button pushed function: GenerateInputsButton
        function GenerateInputsButtonPushed(app, event)
            
            % frequencies
            if app.freq1.Value > 1
                app.INP.freq.freq_min = app.freq2.Value*1e+6;
                app.INP.freq.freq_max = app.freq3.Value*1e+6;
                app.INP.freq.range = app.freq1.Value ;
            else
                app.INP.freq.freq_min = app.freq2.Value*1e+6;
                app.INP.freq.freq_max = app.freq2.Value*1e+6;
                app.INP.freq.range = app.freq1.Value ;
            end
 
            % general
            app.INP.PWX = app.pwx.Value;
            app.INP.PU = app.pu.Value;
            
            % vie
            if strcmp(app.tucker.Value,'Yes')
                app.INP.VIE_tucker.flag = 1;
            else
                app.INP.VIE_tucker.flag = 0;
            end
            app.INP.VIE_tucker.tol = str2double(app.vsiesolver1.Value)*1e-2;
            
            app.INP.VSIE_solver.name = 'G';
            app.INP.VSIE_solver.inner_iter = app.vsiesolver3.Value;
            app.INP.VSIE_solver.outer_iter = app.vsiesolver2.Value;
            app.INP.VSIE_solver.tol = str2double(app.vsiesolver1.Value);
            
            if strcmp(app.viesolver.Value,'GMRES')
                app.INP.VIE_solver.name = 'G';
                app.INP.VIE_solver.inner_iter = app.viesolver3.Value;
                app.INP.VIE_solver.outer_iter = app.viesolver2.Value;
                app.INP.VIE_solver.tol = str2double(app.viesolver1.Value);
            else
                app.INP.VIE_solver.name = 'B';
                app.INP.VIE_solver.outer_iter = app.viesolver2.Value;
                app.INP.VIE_solver.tol = str2double(app.viesolver1.Value);
            end

            if strcmp(app.siesolver.Value,'LU')
                app.INP.SIE_solver.name = 'L';
            elseif strcmp(app.siesolver.Value,'GMRES')
                app.INP.SIE_solver.name = 'G';
                app.INP.SIE_solver.inner_iter = app.siesolver3.Value;
                app.INP.SIE_solver.outer_iter = app.siesolver2.Value;
                app.INP.SIE_solver.tol = str2double(app.siesolver1.Value);
            elseif strcmp(app.siesolver1.Value,'BICGSTAB')
                app.INP.SIE_solver.name = 'B';
                app.INP.SIE_solver.outer_iter = app.siesolver2.Value;
                app.INP.SIE_solver.tol = str2double(app.siesolver1.Value);
            end
                
            % quads
            app.INP.Np_quad_far = app.viefar.Value;
            app.INP.Np_quad_medium = app.viemedium.Value;
            app.INP.Np_quad_near = app.vienear.Value;
            app.INP.Np_quad_coup_sie = app.vsiesie.Value;
            app.INP.Np_quad_coup_vie = app.vsievie.Value;
            
            % vsie
            app.INP.VSIE_coupling = app.mode.Value;
            app.INP.VSIE_expansion_voxels = str2double(app.expvoxels.Value);
            app.INP.VSIE_solver.name = 'G';
            app.INP.VSIE_solver.inner_iter = app.vsiesolver3.Value;
            app.INP.VSIE_solver.outer_iter = app.vsiesolver2.Value;
            app.INP.VSIE_solver.tol = str2double(app.vsiesolver1.Value);
            
            update_inputs(app.CallingApp, app.INP);
            delete(app)
        end

        % Value changed function: freq1
        function freq1ValueChanged(app, event)
            value = app.freq1.Value;
            
            if value > 1
                app.freq3.Visible = 'on';
                app.freqLabel_3.Visible = 'on';
                app.freqLabel.Text = 'Minimum (MHz)';
                app.freq2.Limits = [60,app.freq3.Value];
                app.freq3.Limits = [app.freq2.Value,6000];
            else
                app.freq3.Visible = 'off';
                app.freqLabel_3.Visible = 'off';
                app.freqLabel.Text = 'Frequency (MHz)';
            end
        end

        % Value changed function: freq2
        function freq2ValueChanged(app, event)
            app.freq2.Limits = [60,app.freq3.Value];
            app.freq3.Limits = [app.freq2.Value,6000];
        end

        % Value changed function: freq3
        function freq3ValueChanged(app, event)
            app.freq2.Limits = [60,app.freq3.Value];
            app.freq3.Limits = [app.freq2.Value,6000];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.generate_advanced_input_app = uifigure('Visible', 'off');
            app.generate_advanced_input_app.Name = 'UI Figure';
            app.generate_advanced_input_app.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton_2 = uibutton(app.generate_advanced_input_app, 'push');
            app.ExitButton_2.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton_2.Text = 'Exit';
            app.EditField = uieditfield(app.generate_advanced_input_app, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Choose Input Parameters';
            app.pwx = uidropdown(app.generate_advanced_input_app);
            app.pwx.Items = {'PWC', 'PWL'};
            app.pwx.Value = 'PWC';
            app.viesolver1 = uidropdown(app.generate_advanced_input_app);
            app.viesolver1.Items = {'1e-2', '1e-3', '1e-4', '1e-5', '1e-6', '1e-7', '1e-8', '1e-9', '1e-10', '1e-11', '1e-12', '1e-13', '1e-14', '1e-15'};
            app.viesolver1.Value = '1e-5';
            app.pu = uidropdown(app.generate_advanced_input_app);
            app.pu.Items = {'CPU', 'GPU'};
            app.pu.Value = 'CPU';
            app.freq2 = uieditfield(app.generate_advanced_input_app, 'numeric');
            app.freq2.Limits = [6 6000];
            app.freq2.ValueChangedFcn = createCallbackFcn(app, @freq2ValueChanged, true);
            app.freq2.Value = 297.2;
            app.GenerateInputsButton = uibutton(app.generate_advanced_input_app, 'push');
            app.GenerateInputsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateInputsButtonPushed, true);
            app.GenerateInputsButton.Text = 'Generate Inputs';
            app.viesolver = uidropdown(app.generate_advanced_input_app);
            app.viesolver.Items = {'GMRES', 'BICGSTAB'};
            app.viesolver.ValueChangedFcn = createCallbackFcn(app, @viesolverValueChanged, true);
            app.viesolver.Value = 'GMRES';
            app.viesolver3 = uieditfield(app.generate_advanced_input_app, 'numeric');
            app.viesolver3.Limits = [1 1000];
            app.viesolver3.ValueDisplayFormat = '%.0f';
            app.viesolver3.Value = 50;
            app.viesolver2 = uieditfield(app.generate_advanced_input_app, 'numeric');
            app.viesolver2.Limits = [1 1000];
            app.viesolver2.ValueDisplayFormat = '%.0f';
            app.viesolver2.Value = 200;
            app.tucker = uidropdown(app.generate_advanced_input_app);
            app.tucker.Items = {'Yes', 'No'};
            app.tucker.Value = 'Yes';
            app.siesolver = uidropdown(app.generate_advanced_input_app);
            app.siesolver.Items = {'LU', 'GMRES', 'BICGSTAB'};
            app.siesolver.ValueChangedFcn = createCallbackFcn(app, @siesolverValueChanged, true);
            app.siesolver.Value = 'LU';
            app.siesolver1 = uidropdown(app.generate_advanced_input_app);
            app.siesolver1.Items = {'1e-2', '1e-3', '1e-4', '1e-5', '1e-6', '1e-7', '1e-8', '1e-9', '1e-10', '1e-11', '1e-12', '1e-13', '1e-14', '1e-15'};
            app.siesolver1.Value = '1e-5';
            app.siesolver3 = uieditfield(app.generate_advanced_input_app, 'numeric');
            app.siesolver3.Limits = [1 1000];
            app.siesolver3.ValueDisplayFormat = '%.0f';
            app.siesolver3.Value = 50;
            app.siesolver2 = uieditfield(app.generate_advanced_input_app, 'numeric');
            app.siesolver2.Limits = [1 1000];
            app.siesolver2.ValueDisplayFormat = '%.0f';
            app.siesolver2.Value = 200;
            app.vsiesolver1 = uidropdown(app.generate_advanced_input_app);
            app.vsiesolver1.Items = {'1e-2', '1e-3', '1e-4', '1e-5', '1e-6', '1e-7', '1e-8', '1e-9', '1e-10', '1e-11', '1e-12', '1e-13', '1e-14', '1e-15'};
            app.vsiesolver1.Value = '1e-5';
            app.vsiesolver3 = uieditfield(app.generate_advanced_input_app, 'numeric');
            app.vsiesolver3.Limits = [1 1000];
            app.vsiesolver3.ValueDisplayFormat = '%.0f';
            app.vsiesolver3.Value = 40;
            app.vsiesolver2 = uieditfield(app.generate_advanced_input_app, 'numeric');
            app.vsiesolver2.Limits = [1 1000];
            app.vsiesolver2.ValueDisplayFormat = '%.0f';
            app.vsiesolver2.Value = 200;
            app.expvoxels = uidropdown(app.generate_advanced_input_app);
            app.expvoxels.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21'};
            app.expvoxels.Value = '5';
            app.freqLabel = uilabel(app.generate_advanced_input_app);
            app.freqLabel.Text = 'Frequency (MHz)';
            app.PWXLabel = uilabel(app.generate_advanced_input_app);
            app.PWXLabel.Text = 'Basis functions';
            app.VIEsolverLabel = uilabel(app.generate_advanced_input_app);
            app.VIEsolverLabel.Text = 'VIE Solver';
            app.VIEtoreranceLabel = uilabel(app.generate_advanced_input_app);
            app.VIEtoreranceLabel.Text = 'Tolerance';
            app.Tucker = uilabel(app.generate_advanced_input_app);
            app.Tucker.Text = 'Tucker compression';
            app.DeviceLabel = uilabel(app.generate_advanced_input_app);
            app.DeviceLabel.Text = 'Device';
            app.SIESolverLabel = uilabel(app.generate_advanced_input_app);
            app.SIESolverLabel.Text = 'SIE Solver';
            app.VSIESolverGMRESLabel = uilabel(app.generate_advanced_input_app);
            app.VSIESolverGMRESLabel.Text = 'VSIE Solver (GMRES)';
            app.ExpansionVoxelsLabel = uilabel(app.generate_advanced_input_app);
            app.ExpansionVoxelsLabel.Text = 'Expansion Voxels';
            app.IterationsLabel = uilabel(app.generate_advanced_input_app);
            app.IterationsLabel.Text = 'Iterations';
            app.RestartsLabel = uilabel(app.generate_advanced_input_app);
            app.RestartsLabel.Text = 'Restarts';
            app.IterationsLabel_2 = uilabel(app.generate_advanced_input_app);
            app.IterationsLabel_2.Text = 'Iterations';
            app.IterationsLabel_3 = uilabel(app.generate_advanced_input_app);
            app.IterationsLabel_3.Text = 'Iterations';
            app.ToleranceLabel_2 = uilabel(app.generate_advanced_input_app);
            app.ToleranceLabel_2.Text = 'Tolerance';
            app.ToleranceLabel_3 = uilabel(app.generate_advanced_input_app);
            app.ToleranceLabel_3.Text = 'Tolerance';
            app.RestartsLabel_2 = uilabel(app.generate_advanced_input_app);
            app.RestartsLabel_2.Text = 'Restarts';
            app.RestartsLabel_3 = uilabel(app.generate_advanced_input_app);
            app.RestartsLabel_3.Text = 'Restarts';
            app.EditField_5 = uieditfield(app.generate_advanced_input_app, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_5.Value = 'PFFT Coupling';
            app.EditField_6 = uieditfield(app.generate_advanced_input_app, 'text');
            app.EditField_6.Editable = 'off';
            app.EditField_6.Value = 'General Inputs';
            app.EditField_7 = uieditfield(app.generate_advanced_input_app, 'text');
            app.EditField_7.Editable = 'off';
            app.EditField_7.Value = 'Tucker Compression';
            app.EditField_8 = uieditfield(app.generate_advanced_input_app, 'text');
            app.EditField_8.Editable = 'off';
            app.EditField_8.Value = 'Solver Properties';
            app.freq1 = uieditfield(app.generate_advanced_input_app, 'numeric');
            app.freq1.Limits = [1 1000];
            app.freq1.ValueDisplayFormat = '%.0f';
            app.freq1.ValueChangedFcn = createCallbackFcn(app, @freq1ValueChanged, true);
            app.freq1.Value = 1;
            app.freqLabel_2 = uilabel(app.generate_advanced_input_app);
            app.freqLabel_2.Text = 'Number of Frequencies';
            app.freq3 = uieditfield(app.generate_advanced_input_app, 'numeric');
            app.freq3.Limits = [6 6000];
            app.freq3.ValueChangedFcn = createCallbackFcn(app, @freq3ValueChanged, true);
            app.freq3.Value = 298.2;
            app.freqLabel_3 = uilabel(app.generate_advanced_input_app);
            app.freqLabel_3.Text = 'Maximum (MHz)';
            app.EditField_11 = uieditfield(app.generate_advanced_input_app, 'text');
            app.EditField_11.Editable = 'off';
            app.EditField_11.Value = 'Integration - Quadrature Points';
            app.VoxelnearinteractionsEditFieldLabel = uilabel(app.generate_advanced_input_app);
            app.VoxelnearinteractionsEditFieldLabel.Text = 'Voxel near interactions ';
            app.vienear = uieditfield(app.generate_advanced_input_app, 'numeric');
            app.vienear.Limits = [5 30];
            app.vienear.ValueDisplayFormat = '%.0f';
            app.vienear.Value = 20;
            app.VoxelmediuminteractionsEditFieldLabel = uilabel(app.generate_advanced_input_app);
            app.VoxelmediuminteractionsEditFieldLabel.Text = 'Voxel medium interactions ';
            app.viemedium = uieditfield(app.generate_advanced_input_app, 'numeric');
            app.viemedium.Limits = [3 30];
            app.viemedium.Value = 10;
            app.VoxelfarinteractionsEditFieldLabel = uilabel(app.generate_advanced_input_app);
            app.VoxelfarinteractionsEditFieldLabel.Text = 'Voxel far interactions ';
            app.viefar = uieditfield(app.generate_advanced_input_app, 'numeric');
            app.viefar.Limits = [2 30];
            app.viefar.Value = 4;
            app.CouplingVIEquadraturepointsEditFieldLabel = uilabel(app.generate_advanced_input_app);
            app.CouplingVIEquadraturepointsEditFieldLabel.Text = 'Coupling VIE quadrature points';
            app.vsievie = uieditfield(app.generate_advanced_input_app, 'numeric');
            app.vsievie.Limits = [1 30];
            app.vsievie.Value = 2;
            app.CouplingSIEquadraturepointsEditFieldLabel = uilabel(app.generate_advanced_input_app);
            app.CouplingSIEquadraturepointsEditFieldLabel.Text = 'Coupling SIE quadrature points';
            app.vsiesie = uieditfield(app.generate_advanced_input_app, 'numeric');
            app.vsiesie.Limits = [1 30];
            app.vsiesie.Value = 5;
            app.mode = uidropdown(app.generate_advanced_input_app);
            app.mode.Items = {'Coupled', 'Decoupled'};
            app.mode.Value = 'Coupled';
            app.VSIEsolvermodeLabel = uilabel(app.generate_advanced_input_app);
            app.VSIEsolvermodeLabel.Text = 'VSIE solver mode';
            % Show the figure after all components are created
            app.generate_advanced_input_app.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_generate_advanced_input(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.generate_advanced_input_app)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.generate_advanced_input_app)
        end
    end
end