classdef app_visualize_results < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        visualize_app                  matlab.ui.Figure
        Button                         matlab.ui.control.Button
        ExitButton_2                   matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        SelectcuttoviewDropDownLabel   matlab.ui.control.Label
        SelectcuttoviewDropDown        matlab.ui.control.DropDown
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        ElectricField                  matlab.ui.control.DropDown
        MagneticField                  matlab.ui.control.DropDown
        UIAxes_4                       matlab.ui.control.UIAxes
        SelectparametersDropDown       matlab.ui.control.DropDown
        UIAxes_5                       matlab.ui.control.UIAxes
        PolarisationCurrentButton      matlab.ui.control.Button
        Slider                         matlab.ui.control.Slider
        Slider_1                       matlab.ui.control.Slider
        Slider_2                       matlab.ui.control.Slider
        Slider_4                       matlab.ui.control.Slider
        SelectporttoviewDropDownLabel  matlab.ui.control.Label
        SelectporttoviewDropDown       matlab.ui.control.DropDown
        ElectricalPropertiesDropDown   matlab.ui.control.DropDown
        EditField_13                   matlab.ui.control.EditField
        EditField_14                   matlab.ui.control.EditField
        EditField_15                   matlab.ui.control.EditField
        EditField_16                   matlab.ui.control.EditField
        exportButton                   matlab.ui.control.Button
        SelectfrequencytoviewDropDownLabel  matlab.ui.control.Label
        SelectfrequencytoviewDropDown  matlab.ui.control.DropDown
        SelectfrequencytoviewDropDownLabel_2  matlab.ui.control.Label
        SurfaceCurrentsButton          matlab.ui.control.Button
        UIAxes_7                       matlab.ui.control.UIAxes
        AxisRotationSwitch             matlab.ui.control.Switch
        EditField_17                   matlab.ui.control.EditField
        SaveButton                     matlab.ui.control.Button
        SaveButton_1                   matlab.ui.control.Button
        SaveButton_2                   matlab.ui.control.Button
        SaveButton_3                   matlab.ui.control.Button
        SaveButton_5                   matlab.ui.control.Button
        SaveButton_4                   matlab.ui.control.Button
        RefineDropDownLabel            matlab.ui.control.Label
        RefineDropDown_2               matlab.ui.control.DropDown
        RefineDropDown_2Label          matlab.ui.control.Label
        RefineDropDown_1               matlab.ui.control.DropDown
        RefineDropDown_3Label          matlab.ui.control.Label
        RefineDropDown_4               matlab.ui.control.DropDown
    end


    properties (Access = private)
        SOLUTION % Solution struct
        INP % Input struct
        FlagsIE
        CallingApp   % Main app    
    end

    methods (Access = private)
    
        function[Ae] = Triangle_area(app,r1,r2,r3)
            ls = [r2(1)-r3(1) r3(1)-r1(1) r1(1)-r2(1); r2(2)-r3(2) r3(2)-r1(2) r1(2)-r2(2); r2(3)-r3(3) r3(3)-r1(3) r1(3)-r2(3)];
            aa = sqrt(ls(1,1)^2 + ls(2,1)^2 + ls(3,1)^2);
            bb = sqrt(ls(1,2)^2 + ls(2,2)^2 + ls(3,2)^2);
            cc = sqrt(ls(1,3)^2 + ls(2,3)^2 + ls(3,3)^2);
            s = (aa+bb+cc)/2;
            Ae = sqrt(s*(s-aa)*(s-bb)*(s-cc));
        end
        
        function [A_f] = interpolate_PWL(app,A,stepi)
            [L,M,N,~] = size(A);
            L_f = zeros(L,stepi);
            M_f = zeros(M,stepi);
            N_f = zeros(N,stepi);
            for i = 1:stepi
                L_f(:,i) = i:stepi:stepi*L-(stepi-i);
                M_f(:,i) = i:stepi:stepi*M-(stepi-i);
                N_f(:,i) = i:stepi:stepi*N-(stepi-i);
            end
            A_f = zeros(stepi*L,stepi*M,stepi*N,3);
            for l = 1:stepi
                for m = 1:stepi
                    for n = 1:stepi
                        l_step = -(stepi-(2*l-1))/(2*stepi);
                        m_step = -(stepi-(2*m-1))/(2*stepi);
                        n_step = -(stepi-(2*n-1))/(2*stepi);
                        for i = 1:3                    
                            A_f (L_f(:,l),M_f(:,m),N_f(:,n),i) = A(:,:,:,(i-1)*4+1) +  l_step * A(:,:,:,(i-1)*4+2) + m_step *A(:,:,:,(i-1)*4+3) + n_step *A(:,:,:,(i-1)*4+4);
                        end
                    end
                end
            end
        end
        
        function[H] = get_slice(app,E,sac,cut)
            [n1,n2,n3] = size(E);
            if strcmp(sac,'Sagittal')
                H = squeeze(E(ceil(n1*cut),:,:));
            elseif strcmp(sac,'Coronal')
                H = squeeze(E(:,ceil(n2*cut),:));
            elseif strcmp(sac,'Axial')
                H = squeeze(E(:,:,ceil(n3*cut)));
            end
        end

        function[H] = get_port_frequency(app,E,port_cut,freq_cut)
            port_length = length(app.SelectporttoviewDropDown.Items);
            freq_length = length(app.SelectfrequencytoviewDropDown.Items);
            H = squeeze(E);
            if ndims(H) == 6
                H = squeeze(H(:,:,:,:,port_cut,freq_cut));
            elseif ndims(H) == 5
                if port_length == 1
                    H = squeeze(H(:,:,:,:,freq_cut));
                elseif freq_length == 1
                    H = squeeze(H(:,:,:,:,port_cut));
                end
            elseif ndims(H) == 4 && port_length > 1 && freq_length > 1
                H = squeeze(H(:,:,port_cut,freq_cut));
            elseif ndims(H) == 4 && (port_length > 1 || freq_length > 1)
                if port_length == 1
                    H = squeeze(H(:,:,:,freq_cut));
                elseif freq_length == 1
                    H = squeeze(H(:,:,:,port_cut));
                end
            elseif ndims(H) == 3 && port_length > 1 && freq_length > 1
                H = squeeze(E(:,port_cut,freq_cut));
            elseif ndims(H) == 3 && (port_length > 1 || freq_length > 1)
                if port_length == 1
                    H = squeeze(H(:,:,freq_cut));
                elseif freq_length == 1
                    H = squeeze(H(:,:,port_cut));
                end
            end
        end
        
        function[H] = get_magnitude(app,E)
            [~,~,~,n] = size(E);
            if n == 3
                H = sqrt(abs(E(:,:,:,1)).^2+abs(E(:,:,:,2)).^2+abs(E(:,:,:,3)).^2) ;
            elseif n == 12
                H = sqrt(abs(E(:,:,:,1)).^2+abs(E(:,:,:,5)).^2+abs(E(:,:,:,9)).^2) ;
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, value, marieapp)
            if str2double(value)
                app.visualize_app.Color = [0.302 0.749 0.9294];
            else
                app.visualize_app.Color = [1 1 1];
            end
            if str2double(value)
                app.UIAxes.BackgroundColor = [0.302 0.749 0.9294];
                app.UIAxes_2.BackgroundColor = [0.302 0.749 0.9294];
                app.UIAxes_3.BackgroundColor = [0.302 0.749 0.9294];
                app.UIAxes_4.BackgroundColor = [0.302 0.749 0.9294];
                app.UIAxes_5.BackgroundColor = [0.302 0.749 0.9294];
                app.UIAxes_7.BackgroundColor = [0.302 0.749 0.9294];
            else
                app.UIAxes.BackgroundColor = [1 1 1];
                app.UIAxes_2.BackgroundColor = [1 1 1];
                app.UIAxes_3.BackgroundColor = [1 1 1];
                app.UIAxes_4.BackgroundColor = [1 1 1];
                app.UIAxes_5.BackgroundColor = [1 1 1];
                app.UIAxes_7.BackgroundColor = [1 1 1];
            end
            
            app.CallingApp = marieapp;
            app.SOLUTION = app.CallingApp.SOLUTION;
            
            if isscalar(app.SOLUTION.freqs_)
                app.INP.freq.freq_min = app.SOLUTION.freqs_;
                app.INP.freq.freq_max = app.SOLUTION.freqs_;
                app.INP.freq.range = 1;
            else
                app.INP.freq = app.SOLUTION.freqs_;
            end
            
            if size(app.SOLUTION.Jb_polarization_,4) == 12
                app.INP.PWX = 'PWL';
            elseif  size(app.SOLUTION.Jb_polarization_,4) == 3
                app.INP.PWX = 'PWC';
            end
            
            for i = 1:size(app.SOLUTION.Jb_polarization_,5)
                app.SelectporttoviewDropDown.Items(i) = {num2str(i)};
            end
            app.SelectfrequencytoviewDropDown.Items = cellstr(string(linspace(app.INP.freq.freq_min,app.INP.freq.freq_max,app.INP.freq.range)*1e-6));
            for i = 1:size(app.SOLUTION.Jb_polarization_,5)
                app.SelectparametersDropDown.Items(i+3) = {strcat('S',app.SelectporttoviewDropDown.Value,num2str(i))};
            end
            app.ElectricField.Items(1) = {'No result selected'};
            app.ElectricField.Items(2) = {'Total Electric Field'};
            app.ElectricField.Items(3) = {'Absorbed Power'}; 

            app.MagneticField.Items(1) = {'No result selected'};
            app.MagneticField.Items(2) = {'Total Magnetic Field'};
            app.MagneticField.Items(3) = {'b1+'};
            app.MagneticField.Items(4) = {'b1-'};  
            
            app.SaveButton.Enable = 'off';
            app.SaveButton_1.Enable = 'off';
            app.SaveButton_2.Enable = 'off';
            app.SaveButton_3.Enable = 'off';
            app.SaveButton_4.Enable = 'off';
            app.SaveButton_5.Enable = 'off';
            app.AxisRotationSwitch.Enable = 'off';
            app.RefineDropDown_1.Visible = 'off';
            app.RefineDropDown_2.Visible = 'off';
            app.RefineDropDown_4.Visible = 'off';
            app.RefineDropDown_1.Enable = 'off';
            app.RefineDropDown_2.Enable = 'off';
            app.RefineDropDown_4.Enable = 'off';
            app.RefineDropDownLabel.Visible = 'off';
            app.RefineDropDown_2Label.Visible = 'off';
            app.RefineDropDown_3Label.Visible = 'off';
            
        end

        % Callback function: ExitButton_2, visualize_app
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Callback function: ElectricalPropertiesDropDown, Slider
        function ElectricalProperties(app, event)
            if ~strcmp(app.ElectricalPropertiesDropDown.Value,'No result selected')
                slide_value = app.Slider.Value;
                sac = app.SelectcuttoviewDropDown.Value;
                if strcmp(app.ElectricalPropertiesDropDown.Value,'Relative Permittivity')
                    A = app.get_slice(app.SOLUTION.epsilon_r_,sac,slide_value);
                elseif strcmp(app.ElectricalPropertiesDropDown.Value,'Conductivity')
                    A = app.get_slice(app.SOLUTION.sigma_e_,sac,slide_value);
                end
                imagesc(app.UIAxes,A);
                colormap(app.UIAxes,'hot');
                colorbar(app.UIAxes);
                app.SaveButton.Enable = 'on';
            else
                app.UIAxes.cla;
                app.SaveButton.Enable = 'off';
            end
        end

        % Callback function: ElectricField, Slider_1
        function ElectricFieldRelatedResults(app, event)
            if ~strcmp(app.ElectricField.Value,'No result selected')
                port = str2double(app.SelectporttoviewDropDown.Value);
                freq = find(ismember(app.SelectfrequencytoviewDropDown.Items,app.SelectfrequencytoviewDropDown.Value));
                slide_value = app.Slider_1.Value;
                sac = app.SelectcuttoviewDropDown.Value;
                
                if strcmp(app.ElectricField.Value,'Total Electric Field')
                    A = app.SOLUTION.E_tot_;
                    A = app.get_port_frequency(A,port,freq);
                    A = app.get_magnitude(A);
                elseif strcmp(app.ElectricField.Value,'Absorbed Power')
                    A = app.SOLUTION.P_absorbed_;
                    A = app.get_port_frequency(A,port,freq);
                end
                A = app.get_slice(A,sac,slide_value);
                    
                if strcmp(app.INP.PWX,'PWL')
                    app.RefineDropDown_1.Visible = 'on';
                    app.RefineDropDown_2Label.Visible = 'on';
                    app.RefineDropDown_1.Enable = 'on';
                    if strcmp(app.ElectricField.Value,'Absorbed Power')
                        app.RefineDropDown_1.Visible = 'off';
                        app.RefineDropDown_2Label.Visible = 'off';
                        app.RefineDropDown_1.Enable = 'off';
                    end
                end
                
                imagesc(app.UIAxes_2,A);
                colormap(app.UIAxes_2,'hot');
                colorbar(app.UIAxes_2);
                app.SaveButton_1.Enable = 'on';
            else
                app.RefineDropDown_1.Visible = 'off';
                app.RefineDropDown_1.Value = '1';
                app.RefineDropDown_2Label.Visible = 'off';
                app.RefineDropDown_1.Enable = 'off';
                app.UIAxes_2.cla;
                app.SaveButton_1.Enable = 'off';
            end
        end

        % Callback function: MagneticField, Slider_2
        function MagneticFieldRelatedResults(app, event)
            if ~strcmp(app.MagneticField.Value,'No result selected')
                port = str2double(app.SelectporttoviewDropDown.Value);
                freq = find(ismember(app.SelectfrequencytoviewDropDown.Items,app.SelectfrequencytoviewDropDown.Value));
                slide_value = app.Slider_2.Value;
                sac = app.SelectcuttoviewDropDown.Value;
                if strcmp(app.MagneticField.Value,'Total Magnetic Field')
                    A = app.SOLUTION.H_tot_;
                    A = app.get_port_frequency(A,port,freq);
                    A = app.get_magnitude(A);
                    A = app.get_slice(A,sac,slide_value);
                elseif strcmp(app.MagneticField.Value,'b1+')
                    A = app.SOLUTION.B1_plus_;
                    A = app.get_port_frequency(A,port,freq);
                    A = app.get_slice(A,sac,slide_value);
                elseif strcmp(app.MagneticField.Value,'b1-')
                    A = app.SOLUTION.B1_minus_;
                    A = app.get_port_frequency(A,port,freq);
                    A = app.get_slice(A,sac,slide_value);
                end
                if strcmp(app.INP.PWX,'PWL')
                    app.RefineDropDown_2.Visible = 'on';
                    app.RefineDropDownLabel.Visible = 'on';
                    app.RefineDropDown_2.Enable = 'on';
                end
                imagesc(app.UIAxes_3,A);
                colormap(app.UIAxes_3,'hot');
                colorbar(app.UIAxes_3);
                app.SaveButton_2.Enable = 'on';
            else
                app.RefineDropDown_2.Visible = 'off';
                app.RefineDropDown_2.Value = '1';
                app.RefineDropDownLabel.Visible = 'off';
                app.RefineDropDown_2.Enable = 'off';
                app.UIAxes_3.cla;
                app.SaveButton_2.Enable = 'off';
            end
        end

        % Callback function: PolarisationCurrentButton, Slider_4
        function PolarisationCurrentButtonPushed(app, event)
            port = str2double(app.SelectporttoviewDropDown.Value);
            freq = find(ismember(app.SelectfrequencytoviewDropDown.Items,app.SelectfrequencytoviewDropDown.Value));
            slide_value = app.Slider_4.Value;
            sac = app.SelectcuttoviewDropDown.Value;
            A = app.get_port_frequency(app.SOLUTION.Jb_polarization_,port,freq);
            A = app.get_magnitude(A);
            A = app.get_slice(A,sac,slide_value);
            
            if strcmp(app.INP.PWX,'PWL') 
                app.RefineDropDown_4.Visible = 'on';
                app.RefineDropDown_4.Value = '1';
                app.RefineDropDown_2Label.Visible = 'on';
                app.RefineDropDown_4.Enable = 'on';
            end
            
            imagesc(app.UIAxes_5,A);
            colormap(app.UIAxes_5,'hot');
            colorbar(app.UIAxes_5);
            app.SaveButton_4.Enable = 'on';
        end

        % Value changed function: SelectparametersDropDown
        function SelectparametersDropDownValueChanged(app, event)
            valuespzp = app.SelectparametersDropDown.Value;
            if strcmp(valuespzp,'No set selected')
                app.UIAxes_4.Visible = 'off';
                colorbar(app.UIAxes_4,'delete');
                app.UIAxes_4.cla;
                app.SaveButton_3.Enable = 'off';
                app.exportButton.Enable = 'off';
            elseif strcmp(valuespzp,'S matrix') || strcmp(valuespzp,'Z matrix')
                freq = find(ismember(app.SelectfrequencytoviewDropDown.Items,app.SelectfrequencytoviewDropDown.Value));
                if strcmp(valuespzp,'S matrix')
                    params = app.get_port_frequency(app.SOLUTION.S_param_,1,freq);
                elseif strcmp(valuespzp,'Z matrix')
                    params = app.get_port_frequency(real(app.SOLUTION.Z_param_),1,freq);
                end
                app.UIAxes_4.Visible = 'off';
                app.UIAxes_4.cla;
                app.UIAxes_4.XLim = [0 size(params,1)];
                app.UIAxes_4.YLim = [0 size(params,2)];
                imagesc(app.UIAxes_4,params);
                colormap(app.UIAxes_4,'hot');
                colorbar(app.UIAxes_4);
                app.SaveButton_3.Enable = 'on';
                app.exportButton.Enable = 'on';
            else
                colorbar(app.UIAxes_4,'delete');
                app.UIAxes_4.Visible = 'on';
                app.UIAxes_4.XLim = [app.INP.freq.freq_min app.INP.freq.freq_max];
                app.UIAxes_4.YLim = [min(min(min(-20*log10(app.SOLUTION.sparams)))) max(max(max(-20*log10(app.SOLUTION.sparams))))];
                i = str2double(app.SelectporttoviewDropDown.Value);
                str_i = strcat('S',app.SelectporttoviewDropDown.Value);
                j = str2double(erase(valuespzp,str_i));
                Splot = -20*log10(squeeze(abs(app.SOLUTION.S_param_(i,j,:))))';
                freqs = linspace(app.INP.freq.freq_min,app.INP.freq.freq_max,app.INP.freq.range);
                plot(app.UIAxes_4,freqs,Splot);
                xlabel(app.UIAxes_4,'frequencies');
                ylabel(app.UIAxes_4,'S (dB)');
                app.UIAxes_4.XGrid = 'on';
                app.UIAxes_4.YGrid = 'on';
                app.SaveButton_3.Enable = 'on';
                app.exportButton.Enable = 'on';
            end
        end

        % Button pushed function: exportButton
        function exportButtonPushed(app, event)
            freqs = linspace(app.INP.freq.freq_min,app.INP.freq.freq_max,app.INP.freq.range);
            Export_SnP('S_Parameters.snp',app.SOLUTION.S_param_,freqs,'S',50);  
            Export_SnP('Z_Parameters.snp',app.SOLUTION.Z_param_,freqs,'Z',50);
        end

        % Value changed function: SelectporttoviewDropDown
        function SelectporttoviewDropDownValueChanged(app, event)
            value = app.SelectporttoviewDropDown.Value;
            for i = 1:size(app.SOLUTION.Jb_polarization_,4)
                app.SelectparametersDropDown.Items(i+3) = {strcat('S',value,num2str(i))};
            end
        end

        % Value changed function: AxisRotationSwitch
        function AxisRotationSwitchValueChanged(app, event)
            value = app.AxisRotationSwitch.Value;
            if str2double(value)
                rotate3d(app.UIAxes_7,'on');
            else
                rotate3d(app.UIAxes_7,'off');
            end
        end

        % Button pushed function: SurfaceCurrentsButton
        function SurfaceCurrentsButtonPushed(app, event)
            app.UIAxes_7.Visible = 'on';
            first_node  = [3 1 2];
            second_node = [2 3 1];
            freq = find(ismember(app.SelectfrequencytoviewDropDown.Items,app.SelectfrequencytoviewDropDown.Value));
            port = str2double(app.SelectporttoviewDropDown.Value);
            COIL = app.SOLUTION.coil;
            Jc = abs(app.SOLUTION.Jc_surface_(:,port,freq));
            j_cart = zeros(size(COIL.elem,2),3);
            for jj = 1: size(COIL.elem,2)
                rp = COIL.Ct(:,jj);
                r1 = COIL.node(:,COIL.elem(1,jj));
                r2 = COIL.node(:,COIL.elem(2,jj));
                r3 = COIL.node(:,COIL.elem(3,jj));
                Ae = app.Triangle_area(r1,r2,r3);
                Ae1 = app.Triangle_area(rp,r2,r3);
                Ae2 = app.Triangle_area(r1,rp,r3);
                Ae3 = app.Triangle_area(r1,r2,rp);
                zs = [Ae1 Ae2 Ae3]./Ae;
                ed = abs(COIL.etod(:,jj));
                lo = [r2(1)-r3(1) r3(1)-r1(1) r1(1)-r2(1); r2(2)-r3(2) r3(2)-r1(2) r1(2)-r2(2); r2(3)-r3(3) r3(3)-r1(3) r1(3)-r2(3)];
                for id = 1:3
                    if COIL.index(ed(id))~=0
                        i_1 = second_node(id);
                        i_2 = first_node(id);
                        L_i = sqrt(lo(1,id)^2 + lo(2,id)^2 + lo(3,id)^2);
                        j_cart(jj,:) = j_cart(jj,:) + (L_i/(2*Ae)) * (zs(i_2)*lo(:,i_1)' - zs(i_1)*lo(:,i_2)') * sign(COIL.etod(id,jj)) * Jc(COIL.index(ed(id)),1);
                    end
                end
            end
            j_cart = real(j_cart);
            quiver3(app.UIAxes_7,COIL.Ct(1,:)',COIL.Ct(2,:)',COIL.Ct(3,:)',j_cart(:,1),j_cart(:,2),j_cart(:,3),2,'k');
            xlabel(app.UIAxes_7,'x');
            ylabel(app.UIAxes_7,'y');
            zlabel(app.UIAxes_7,'z');
            app.UIAxes_7.XGrid = 'on';
            app.UIAxes_7.YGrid = 'on';
            app.UIAxes_7.ZGrid = 'on';  
            app.AxisRotationSwitch.Enable = 'on';
        end

        % Value changed function: RefineDropDown_4
        function RefineButton_3Pushed(app, event)
            port = str2double(app.SelectporttoviewDropDown.Value);
            freq = find(ismember(app.SelectfrequencytoviewDropDown.Items,app.SelectfrequencytoviewDropDown.Value));
            slide_value = app.Slider_4.Value;
            sac = app.SelectcuttoviewDropDown.Value;
            A = app.get_port_frequency(app.SOLUTION.Jb_polarization_,port,freq);
            A_f = app.interpolate_PWL(A,str2double(app.RefineDropDown_4.Value));  
            A_f = app.get_magnitude(A_f);
            A_f = app.get_slice(A_f,sac,slide_value);
            imagesc(app.UIAxes_5,A_f);
            colormap(app.UIAxes_5,'hot');
            colorbar(app.UIAxes_5);
            app.SaveButton_4.Enable = 'on'; 
        end

        % Value changed function: RefineDropDown_2
        function RefineButton_2Pushed(app, event)
            port = str2double(app.SelectporttoviewDropDown.Value);
            freq = find(ismember(app.SelectfrequencytoviewDropDown.Items,app.SelectfrequencytoviewDropDown.Value));
            slide_value = app.Slider_2.Value;
            sac = app.SelectcuttoviewDropDown.Value;
            if strcmp(app.MagneticField.Value,'Total Magnetic Field')
                A = app.SOLUTION.H_tot_;
                A = app.get_port_frequency(A,port,freq);
                A_f = app.interpolate_PWL(A,str2double(app.RefineDropDown_2.Value));
                A_f = app.get_magnitude(A_f);
                A_f = app.get_slice(A_f,sac,slide_value);
            elseif strcmp(app.MagneticField.Value,'b1+')
                A = app.SOLUTION.B1_plus_;
                A = app.get_port_frequency(A,port,freq);
                A_f = app.interpolate_PWL(A,str2double(app.RefineDropDown_2.Value));
                A_f = 4*pi*1e-7*abs(A_f(:,:,:,1)+1i*A_f(:,:,:,2));
                A_f = app.get_slice(A_f,sac,slide_value);
            elseif strcmp(app.MagneticField.Value,'b1-')
                A = app.SOLUTION.B1_minus_;
                A = app.get_port_frequency(A,port,freq);
                A_f = app.interpolate_PWL(A,str2double(app.RefineDropDown_2.Value));
                A_f = 4*pi*1e-7*abs(A_f(:,:,:,1)-1i*A_f(:,:,:,2));
                A_f = app.get_slice(A_f,sac,slide_value);
            end
            
            imagesc(app.UIAxes_3,A_f);
            colormap(app.UIAxes_3,'hot');
            colorbar(app.UIAxes_3);
            app.SaveButton_2.Enable = 'on';
        end

        % Value changed function: RefineDropDown_1
        function RefineButtonPushed(app, event)
            port = str2double(app.SelectporttoviewDropDown.Value);
            freq = find(ismember(app.SelectfrequencytoviewDropDown.Items,app.SelectfrequencytoviewDropDown.Value));
            slide_value = app.Slider_1.Value;
            sac = app.SelectcuttoviewDropDown.Value;
                
            if strcmp(app.ElectricField.Value,'Total Electric Field')
                A = app.SOLUTION.E_tot_;
            end
            A = app.get_port_frequency(A,port,freq);
            A_f = app.interpolate_PWL(A,str2double(app.RefineDropDown_1.Value));
            A_f = app.get_magnitude(A_f);
            A_f = app.get_slice(A_f,sac,slide_value);
            
            imagesc(app.UIAxes_2,A_f);
            colormap(app.UIAxes_2,'hot');
            colorbar(app.UIAxes_2);
            app.SaveButton_1.Enable = 'on';    
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            slide_value = app.Slider.Value;
            sac = app.SelectcuttoviewDropDown.Value;
            [n1,n2,n3] = size(app.SOLUTION.epsilon_r_);
            if strcmp(sac,'Sagittal')
                n = n1;
            elseif strcmp(sac,'Coronal')
                n = n2;
            elseif strcmp(sac,'Axial')
                n = n3;
            end
            if strcmp(app.ElectricalPropertiesDropDown.Value,'Relative Permittivity')
                app.CallingApp.obj_vis.visualize_properties(app.SOLUTION.epsilon_r_,sac,ceil(slide_value*n));
            elseif strcmp(app.ElectricalPropertiesDropDown.Value,'Conductivity')
                app.CallingApp.obj_vis.visualize_properties(app.SOLUTION.sigma_e_,sac,ceil(slide_value*n));
            end
        end

        % Button pushed function: SaveButton_1
        function SaveButton_1Pushed(app, event)
            port = str2double(app.SelectporttoviewDropDown.Value);
            freq = find(ismember(app.SelectfrequencytoviewDropDown.Items,app.SelectfrequencytoviewDropDown.Value));
            slide_value = app.Slider_1.Value;
            sac = app.SelectcuttoviewDropDown.Value;
            [n1,n2,n3,~] = size(app.SOLUTION.E_tot_);
            if strcmp(sac,'Sagittal')
                n = n1;
            elseif strcmp(sac,'Coronal')
                n = n2;
            elseif strcmp(sac,'Axial')
                n = n3;
            end
            ref_step = str2double(app.RefineDropDown_1.Value);
            if strcmp(app.ElectricField.Value,'Total Electric Field')
                A = app.SOLUTION.E_tot_;
                if ref_step > 1
                    app.CallingApp.obj_vis.visualize_InterpolatePlot(A,sac,ceil(slide_value*n)*ref_step,port,freq,ref_step)
                else
                    app.CallingApp.obj_vis.visualize_fields(A,sac,ceil(slide_value*n),port,freq);    
                end
            elseif strcmp(app.ElectricField.Value,'Absorbed Power')
                A = app.SOLUTION.P_absorbed_;
                app.CallingApp.obj_vis.visualize_fields(A,sac,ceil(slide_value*n),port,freq);    
            end
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            port = str2double(app.SelectporttoviewDropDown.Value);
            freq = find(ismember(app.SelectfrequencytoviewDropDown.Items,app.SelectfrequencytoviewDropDown.Value));
            slide_value = app.Slider_2.Value;
            sac = app.SelectcuttoviewDropDown.Value;
            [n1,n2,n3,~] = size(app.SOLUTION.H_tot_);
            if strcmp(sac,'Sagittal')
                n = n1;
            elseif strcmp(sac,'Coronal')
                n = n2;
            elseif strcmp(sac,'Axial')
                n = n3;
            end
            ref_step = str2double(app.RefineDropDown_2.Value);
            if strcmp(app.MagneticField.Value,'Total Magnetic Field')
                A = app.SOLUTION.H_tot_;
                if ref_step > 1
                    app.CallingApp.obj_vis.visualize_InterpolatePlot(A,sac,ceil(slide_value*n)*ref_step,port,freq,ref_step)
                else
                    app.CallingApp.obj_vis.visualize_fields(A,sac,ceil(slide_value*n),port,freq);    
                end
            elseif strcmp(app.MagneticField.Value,'b1+')
                A = app.SOLUTION.B1_plus_;
                app.CallingApp.obj_vis.visualize_fields(A,sac,ceil(slide_value*n),port,freq);    
            elseif strcmp(app.MagneticField.Value,'b1-')
                A = app.SOLUTION.B1_minus_;
                app.CallingApp.obj_vis.visualize_fields(A,sac,ceil(slide_value*n),port,freq);    
            end    
        end

        % Button pushed function: SaveButton_4
        function SaveButton_4Pushed2(app, event)
            port = str2double(app.SelectporttoviewDropDown.Value);
            freq = find(ismember(app.SelectfrequencytoviewDropDown.Items,app.SelectfrequencytoviewDropDown.Value));
            slide_value = app.Slider_4.Value;
            sac = app.SelectcuttoviewDropDown.Value;
            [n1,n2,n3,~] = size(app.SOLUTION.Jb_polarization_);
            if strcmp(sac,'Sagittal')
                n = n1;
            elseif strcmp(sac,'Coronal')
                n = n2;
            elseif strcmp(sac,'Axial')
                n = n3;
            end
            A = app.SOLUTION.Jb_polarization_;
            ref_step = str2double(app.RefineDropDown_4.Value);
            if ref_step > 1
                app.CallingApp.obj_vis.visualize_InterpolatePlot(A,sac,ceil(slide_value*n)*ref_step,port,freq,ref_step)
            else
                app.CallingApp.obj_vis.visualize_fields(A,sac,ceil(slide_value*n),port,freq);    
            end   
        end

        % Button pushed function: SaveButton_3
        function SaveButton_3Pushed(app, event)
            valuespzp = app.SelectparametersDropDown.Value;
            if strcmp(valuespzp,'S matrix') || strcmp(valuespzp,'Z matrix')
                freq = find(ismember(app.SelectfrequencytoviewDropDown.Items,app.SelectfrequencytoviewDropDown.Value));
                if strcmp(valuespzp,'S matrix')
                    params = app.get_port_frequency(app.SOLUTION.S_param_,1,freq);
                    app.CallingApp.obj_vis.visualize_Sparam_matrix(params,freq);
                elseif strcmp(valuespzp,'Z matrix')
                    params = app.get_port_frequency(real(app.SOLUTION.Z_param_),1,freq);
                    app.CallingApp.obj_vis.visualize_Zparam_matrix(params,freq);
                end
            else
                i = str2double(app.SelectporttoviewDropDown.Value);
                str_i = strcat('S',app.SelectporttoviewDropDown.Value);
                j = str2double(erase(valuespzp,str_i));
                freqs = linspace(app.INP.freq.freq_min,app.INP.freq.freq_max,app.INP.freq.range);
                app.CallingApp.obj_vis.vizualize_frequency_sweep_Sparam(app.SOLUTION.S_param_,i,j,freqs)
            end    
        end

        % Button pushed function: SaveButton_5
        function SaveButton_5Pushed2(app, event)
            freq = find(ismember(app.SelectfrequencytoviewDropDown.Items,app.SelectfrequencytoviewDropDown.Value));
            port = str2double(app.SelectporttoviewDropDown.Value);
            COIL = app.CallingApp.COIL;
            app.CallingApp.obj_vis.visualize_currents(COIL,app.SOLUTION.Jc_surface_,port,freq);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.visualize_app = uifigure('Visible', 'off');
            app.visualize_app.Name = 'UI Figure';
            app.visualize_app.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Button = uibutton(app.visualize_app, 'push');
            app.Button.Icon = 'Marie2logo.jpg';
            app.Button.Text = '';
            app.ExitButton_2 = uibutton(app.visualize_app, 'push');
            app.ExitButton_2.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton_2.Text = 'Exit';
            app.UIAxes = uiaxes(app.visualize_app);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {};
            app.UIAxes.ZTick = [];
            app.UIAxes.ZTickLabel = '';
            app.UIAxes.Visible = 'off';
            app.SelectcuttoviewDropDownLabel = uilabel(app.visualize_app);
            app.SelectcuttoviewDropDownLabel.Text = 'Select cut to view';
            app.SelectcuttoviewDropDown = uidropdown(app.visualize_app);
            app.SelectcuttoviewDropDown.Items = {'Sagittal', 'Coronal', 'Axial'};
            app.SelectcuttoviewDropDown.Value = 'Sagittal';
            app.UIAxes_2 = uiaxes(app.visualize_app);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = {'[]'};
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = {};
            app.UIAxes_2.ZTick = [];
            app.UIAxes_2.ZTickLabel = '';
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.visualize_app);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.XTickLabel = {'[]'};
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.YTickLabel = {};
            app.UIAxes_3.ZTick = [];
            app.UIAxes_3.ZTickLabel = '';
            app.UIAxes_3.Visible = 'off';
            app.ElectricField = uidropdown(app.visualize_app);
            app.ElectricField.Items = {'No result selected'};
            app.ElectricField.ValueChangedFcn = createCallbackFcn(app, @ElectricFieldRelatedResults, true);
            app.ElectricField.Value = 'No result selected';
            app.MagneticField = uidropdown(app.visualize_app);
            app.MagneticField.Items = {'No result selected'};
            app.MagneticField.ValueChangedFcn = createCallbackFcn(app, @MagneticFieldRelatedResults, true);
            app.MagneticField.Value = 'No result selected';
            app.UIAxes_4 = uiaxes(app.visualize_app);
            app.UIAxes_4.ZTick = [];
            app.UIAxes_4.ZTickLabel = '';
            app.UIAxes_4.Visible = 'off';
            app.SelectparametersDropDown = uidropdown(app.visualize_app);
            app.SelectparametersDropDown.Items = {'No set selected', 'S matrix', 'Z matrix'};
            app.SelectparametersDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectparametersDropDownValueChanged, true);
            app.SelectparametersDropDown.Value = 'No set selected';
            app.UIAxes_5 = uiaxes(app.visualize_app);
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.XTickLabel = {'[]'};
            app.UIAxes_5.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_5.YTickLabel = {'[]'};
            app.UIAxes_5.ZTick = [];
            app.UIAxes_5.ZTickLabel = '';
            app.UIAxes_5.Visible = 'off';
            app.PolarisationCurrentButton = uibutton(app.visualize_app, 'push');
            app.PolarisationCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @PolarisationCurrentButtonPushed, true);
            app.PolarisationCurrentButton.Text = 'Polarisation Current';
            app.Slider = uislider(app.visualize_app);
            app.Slider.Limits = [0.0001 1];
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @ElectricalProperties, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 0.5;
            app.Slider_1 = uislider(app.visualize_app);
            app.Slider_1.Limits = [0.0001 1];
            app.Slider_1.MajorTicks = [];
            app.Slider_1.MajorTickLabels = {};
            app.Slider_1.ValueChangingFcn = createCallbackFcn(app, @ElectricFieldRelatedResults, true);
            app.Slider_1.MinorTicks = [];
            app.Slider_1.Value = 0.5;
            app.Slider_2 = uislider(app.visualize_app);
            app.Slider_2.Limits = [0.0001 1];
            app.Slider_2.MajorTicks = [];
            app.Slider_2.MajorTickLabels = {};
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @MagneticFieldRelatedResults, true);
            app.Slider_2.MinorTicks = [];
            app.Slider_2.Value = 0.5;
            app.Slider_4 = uislider(app.visualize_app);
            app.Slider_4.Limits = [0.0001 1];
            app.Slider_4.MajorTicks = [];
            app.Slider_4.MajorTickLabels = {};
            app.Slider_4.ValueChangingFcn = createCallbackFcn(app, @PolarisationCurrentButtonPushed, true);
            app.Slider_4.MinorTicks = [];
            app.Slider_4.Value = 0.5;
            app.SelectporttoviewDropDownLabel = uilabel(app.visualize_app);
            app.SelectporttoviewDropDownLabel.Text = 'Select port to view';
            app.SelectporttoviewDropDown = uidropdown(app.visualize_app);
            app.SelectporttoviewDropDown.Items = {'1'};
            app.SelectporttoviewDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectporttoviewDropDownValueChanged, true);
            app.SelectporttoviewDropDown.Value = '1';
            app.ElectricalPropertiesDropDown = uidropdown(app.visualize_app);
            app.ElectricalPropertiesDropDown.Items = {'No result selected', 'Relative Permittivity', 'Conductivity'};
            app.ElectricalPropertiesDropDown.ValueChangedFcn = createCallbackFcn(app, @ElectricalProperties, true);
            app.ElectricalPropertiesDropDown.Value = 'No result selected';
            app.EditField_13 = uieditfield(app.visualize_app, 'text');
            app.EditField_13.Editable = 'off';
            app.EditField_13.Value = 'Electrical Properties';
            app.EditField_14 = uieditfield(app.visualize_app, 'text');
            app.EditField_14.Editable = 'off';
            app.EditField_14.Value = 'Electric Field Results';
            app.EditField_15 = uieditfield(app.visualize_app, 'text');
            app.EditField_15.Editable = 'off';
            app.EditField_15.Value = 'Magnetic Field Results';
            app.EditField_16 = uieditfield(app.visualize_app, 'text');
            app.EditField_16.Editable = 'off';
            app.EditField_16.Value = 'Visualize S/Z Parameters';
            app.exportButton = uibutton(app.visualize_app, 'push');
            app.exportButton.ButtonPushedFcn = createCallbackFcn(app, @exportButtonPushed, true);
            app.exportButton.Text = 'export';
            app.SelectfrequencytoviewDropDownLabel = uilabel(app.visualize_app);
            app.SelectfrequencytoviewDropDownLabel.Text = 'Select frequency to view';
            app.SelectfrequencytoviewDropDown = uidropdown(app.visualize_app);
            app.SelectfrequencytoviewDropDown.Items = {'0'};
            app.SelectfrequencytoviewDropDown.Value = '0';
            app.SelectfrequencytoviewDropDownLabel_2 = uilabel(app.visualize_app);
            app.SelectfrequencytoviewDropDownLabel_2.Text = 'MHz';
            app.SurfaceCurrentsButton = uibutton(app.visualize_app, 'push');
            app.SurfaceCurrentsButton.ButtonPushedFcn = createCallbackFcn(app, @SurfaceCurrentsButtonPushed, true);
            app.SurfaceCurrentsButton.Text = 'Surface Currents';
            app.UIAxes_7 = uiaxes(app.visualize_app);
            app.UIAxes_7.Visible = 'off';
            app.AxisRotationSwitch = uiswitch(app.visualize_app, 'slider');
            app.AxisRotationSwitch.Items = {'0', '1'};
            app.AxisRotationSwitch.ValueChangedFcn = createCallbackFcn(app, @AxisRotationSwitchValueChanged, true);
            app.AxisRotationSwitch.Value = '0';
            app.EditField_17 = uieditfield(app.visualize_app, 'text');
            app.EditField_17.Editable = 'off';
            app.EditField_17.Value = 'Axis Rotation';
            app.SaveButton = uibutton(app.visualize_app, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SaveButton_1 = uibutton(app.visualize_app, 'push');
            app.SaveButton_1.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_1Pushed, true);
            app.SaveButton_1.Text = 'Save';
            app.SaveButton_2 = uibutton(app.visualize_app, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.SaveButton_3 = uibutton(app.visualize_app, 'push');
            app.SaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_3Pushed, true);
            app.SaveButton_3.Text = 'Save';
            app.SaveButton_5 = uibutton(app.visualize_app, 'push');
            app.SaveButton_5.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_5Pushed2, true);
            app.SaveButton_5.Text = 'Save';
            app.SaveButton_4 = uibutton(app.visualize_app, 'push');
            app.SaveButton_4.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_4Pushed2, true);
            app.SaveButton_4.Text = 'Save';
            app.RefineDropDownLabel = uilabel(app.visualize_app);
            app.RefineDropDownLabel.Text = 'Refine';
            app.RefineDropDown_2 = uidropdown(app.visualize_app);
            app.RefineDropDown_2.Items = {'1', '2', '4', '8'};
            app.RefineDropDown_2.ValueChangedFcn = createCallbackFcn(app, @RefineButton_2Pushed, true);
            app.RefineDropDown_2.Value = '1';
            app.RefineDropDown_2Label = uilabel(app.visualize_app);
            app.RefineDropDown_2Label.Text = 'Refine';
            app.RefineDropDown_1 = uidropdown(app.visualize_app);
            app.RefineDropDown_1.Items = {'1', '2', '4', '8'};
            app.RefineDropDown_1.ValueChangedFcn = createCallbackFcn(app, @RefineButtonPushed, true);
            app.RefineDropDown_1.Value = '1';
            app.RefineDropDown_3Label = uilabel(app.visualize_app);
            app.RefineDropDown_3Label.Text = 'Refine';
            app.RefineDropDown_4 = uidropdown(app.visualize_app);
            app.RefineDropDown_4.Items = {'1', '2', '4', '8'};
            app.RefineDropDown_4.ValueChangedFcn = createCallbackFcn(app, @RefineButton_3Pushed, true);
            app.RefineDropDown_4.Value = '1';
            % Show the figure after all components are created
            app.visualize_app.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_visualize_results(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.visualize_app)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.visualize_app)
        end
    end
end